classdef ASCA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        RefreshButton               matlab.ui.control.Button
        LoaddataButton              matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        BuildASCAModelTab           matlab.ui.container.Tab
        GridLayout3                 matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        TreeRandom                  matlab.ui.container.CheckBoxTree
        RandomfactorsNode           matlab.ui.container.TreeNode
        TreeOrdinal                 matlab.ui.container.CheckBoxTree
        OrdinalfactorsNode_2        matlab.ui.container.TreeNode
        PermutationsSpinner         matlab.ui.control.Spinner
        PermutationsSpinnerLabel    matlab.ui.control.Label
        ModeltypeDropDown           matlab.ui.control.DropDown
        ModeltypeDropDownLabel      matlab.ui.control.Label
        PreprocessingDropDown       matlab.ui.control.DropDown
        PreprocessingDropDownLabel  matlab.ui.control.Label
        FactornamesDropDown         matlab.ui.control.DropDown
        FactornamesDropDownLabel    matlab.ui.control.Label
        DesignmatrixDropDown        matlab.ui.control.DropDown
        DesignmatrixDropDownLabel   matlab.ui.control.Label
        DatamatrixDropDown          matlab.ui.control.DropDown
        DatamatrixDropDownLabel     matlab.ui.control.Label
        NestedfactorsPanel          matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        nestedinLabel               matlab.ui.control.Label
        ParentDropDown_1            matlab.ui.control.DropDown
        ChildDropDown_1             matlab.ui.control.DropDown
        BuildmodelButton            matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        VisualizationTab            matlab.ui.container.Tab
        GridLayoutVis               matlab.ui.container.GridLayout
        FactorDropDownLabel         matlab.ui.control.Label
        FactorInteractionDropDown   matlab.ui.control.DropDown
        PCAVisualizationButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        RawData % Storage for the loaded .mat file
        T
        parglmo
        ascao
        NestingRows = {};
    end

    % Helper functions
    methods (Access = private)

        function UpdateDropdownsFromWorkspace(app)
            wsVars = evalin('base', 'who');
            if isempty(wsVars)
                app.DatamatrixDropDown.Items = {};
                app.DesignmatrixDropDown.Items = {};
                app.FactornamesDropDown.Items = {};
            else
                app.DatamatrixDropDown.Items = wsVars;
                app.DesignmatrixDropDown.Items = wsVars;
                app.FactornamesDropDown.Items = [{''}; wsVars];
            end
        end
        
        function [isValid, names] = ValidateFactorNames(app, varName, F)
            % Initialize defaults
            isValid = true; 
            names = [];
        
            % If the dropdown is empty, it's technically "valid" because it's optional
            if isempty(varName)
                return;
            end
        
            try
                temp_names = evalin('base', varName);
                
                % Validation logic
                isCorrectType = isstring(temp_names) || iscellstr(temp_names);
                isCorrectSize = isvector(temp_names) && length(temp_names) == size(F, 2);
        
                if isCorrectType && isCorrectSize
                    % Convert to column cell array immediately to avoid future errors
                    names = cellstr(temp_names(:));
                else
                    isValid = false;
                end
            catch
                isValid = false;
            end
        end


        function UpdateFactorLists(app)
            % Clear and check validity
            delete(app.TreeOrdinal.Children);
            delete(app.TreeRandom.Children);
            
            designVar = app.DesignmatrixDropDown.Value;
            if isempty(designVar)
                return; 
            end
            
            F = evalin('base', designVar);
            numFactors = size(F, 2);
            [valid, names] = app.ValidateFactorNames(app.FactornamesDropDown.Value, F);
            displayItems = names;
            if ~valid || isempty(names)
                displayItems = arrayfun(@(x) ['F' num2str(x)], 1:numFactors, 'UniformOutput', false);
            end
        
            % --- ORDINAL TREE ---
            ordRoot = uitreenode(app.TreeOrdinal, 'Text', 'Ordinal Factors', 'NodeData', 'header');
            for i = 1:numFactors
                uitreenode(ordRoot, 'Text', displayItems{i}, 'NodeData', i);
            end
            expand(ordRoot); % Show children immediately
        
            % --- RANDOM TREE ---
            ranRoot = uitreenode(app.TreeRandom, 'Text', 'Random Factors', 'NodeData', 'header');
            for i = 1:numFactors
                uitreenode(ranRoot, 'Text', displayItems{i}, 'NodeData', i);
            end
            expand(ranRoot);
            
            % --- NESTED FACTORS ---
            % Update the first pair of dropdowns (the ones you already have in the canvas)
            app.ChildDropDown_1.Items = [{''}; displayItems(:)];
            app.ParentDropDown_1.Items = [{''}; displayItems(:)];
            

            % Set default to empty so the user has to actually pick something
            app.ChildDropDown_1.Value = {''};
            app.ParentDropDown_1.Value = {''};


        end

        function AddNestingRow(app)
            % 1. Increase the row count of the internal grid
            app.GridLayout2.RowHeight = [app.GridLayout2.RowHeight, {'1x'}];
            newRowIdx = length(app.GridLayout2.RowHeight);
            
            % 2. Get current factor items from the existing dropdowns
            items = app.ChildDropDown_1.Items;
            
            % 3. Create Child Dropdown
            newChild = uidropdown(app.GridLayout2);
            newChild.Items = items;
            newChild.Layout.Row = newRowIdx;
            newChild.Layout.Column = 1;
            newChild.Value = {''};
            newChild.ValueChangedFcn = createCallbackFcn(app, @NestingValueChanged, true);
            
            % 4. Create "nested in" Label
            newLabel = uilabel(app.GridLayout2);
            newLabel.Text = 'nested in';
            newLabel.HorizontalAlignment = 'center';
            newLabel.Layout.Row = newRowIdx;
            newLabel.Layout.Column = 2;
            
            % 5. Create Parent Dropdown
            newParent = uidropdown(app.GridLayout2);
            newParent.Items = items;
            newParent.Layout.Row = newRowIdx;
            newParent.Layout.Column = 3;
            newParent.Value = {''};
            newParent.ValueChangedFcn = createCallbackFcn(app, @NestingValueChanged, true);
            
            % 6. Store handles for later retrieval
            app.NestingRows{end+1} = struct('child', newChild, 'parent', newParent);
        end

        function [nestedMatrix] = GetNestedMatrix(app)
            % Collects data from the first row + all dynamic rows
            nestedMatrix = [];
            
            % Check the static first row
            if ~isempty(app.ChildDropDown_1.Value) && ~isempty(app.ParentDropDown_1.Value) ...
                    && ~strcmp(app.ChildDropDown_1.Value, '') && ~strcmp(app.ParentDropDown_1.Value, '')
                
                cIdx = find(strcmp(app.ChildDropDown_1.Items, app.ChildDropDown_1.Value)) - 1;
                pIdx = find(strcmp(app.ParentDropDown_1.Items, app.ParentDropDown_1.Value)) - 1;
                nestedMatrix = [pIdx, cIdx]; % Note the [Parent Child] order per your prompt
            end
            
            % Check all dynamic rows
            for i = 1:length(app.NestingRows)
                cVal = app.NestingRows{i}.child.Value;
                pVal = app.NestingRows{i}.parent.Value;
                
                if ~isempty(cVal) && ~isempty(pVal) && ~strcmp(cVal, '') && ~strcmp(pVal, '')
                    cIdx = find(strcmp(app.NestingRows{i}.child.Items, cVal)) - 1;
                    pIdx = find(strcmp(app.NestingRows{i}.parent.Items, pVal)) - 1;
                    nestedMatrix = [nestedMatrix; pIdx, cIdx];
                end
            end
        end

        function NestingValueChanged(app, event)
                        % 1. Logic to check for self-nesting
                        child = event.Source.Parent.Children(3); % Simple index-based check
                        parent = event.Source.Parent.Children(1);
                        
                        if strcmp(child.Value, parent.Value) && ~strcmp(child.Value, '')
                            uialert(app.UIFigure, 'A factor cannot be nested in itself.', 'Selection Error');
                            event.Source.Value = {''};
                            return;
                        end
                        
                        % 2. Logic to spawn a new row
                        % If the last row is now "complete", add another one
                        if ~strcmp(app.ChildDropDown_1.Value, '') && ~strcmp(app.ParentDropDown_1.Value, '')
                             % Check if dynamic rows exist, if not add first one, or check last dynamic row
                             if isempty(app.NestingRows)
                                 app.AddNestingRow();
                             else
                                 lastRow = app.NestingRows{end};
                                 if ~strcmp(lastRow.child.Value, '') && ~strcmp(lastRow.parent.Value, '')
                                     app.AddNestingRow();
                                 end
                             end
                        end
            end

        function ValidateBuildButton(app)
            % Get selected variable names
            X = app.DatamatrixDropDown.Value;
            F = app.DesignmatrixDropDown.Value;
        
            % Default state is disabled
            app.BuildmodelButton.Enable = 'off';
        
            % Only proceed if both dropdowns have a selection
            if ~isempty(X) && ~isempty(F)
                try
                    % Get variables from base workspace
                    dataMat = evalin('base', X);
                    designMat = evalin('base', F);
        
                    % Condition 1: Both must be 2D
                    is2D = ismatrix(dataMat) && ismatrix(designMat);
                    
                    % Condition 2: First dimension (rows) must match
                    rowsMatch = size(dataMat, 1) == size(designMat, 1);
        
                    if is2D && rowsMatch
                        app.BuildmodelButton.Enable = 'on';
                    end
                catch
                    % If evalin fails (e.g. variable was deleted), keep button off
                    app.BuildmodelButton.Enable = 'off';
                end
            end
        end

   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Select Data File');
            
                if isequal(file, 0)
                    return;
                end
            
                fullPath = fullfile(path, file);
                dataStruct = load(fullPath);
                app.RawData = dataStruct;
            
                % Export to Matlab Workspace
                vars = fieldnames(dataStruct);
                for i = 1:length(vars)
                    assignin('base', vars{i}, dataStruct.(vars{i}));
                end
            
                % Update UI dropdowns
                if ~isempty(vars)
                    app.DatamatrixDropDown.Items = vars;
                    app.DesignmatrixDropDown.Items = vars;
                end

                app.UpdateDropdownsFromWorkspace();

        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.UpdateDropdownsFromWorkspace();
        end

        % Value changed function: DatamatrixDropDown
        function DatamatrixDropDownValueChanged(app, event)
            app.ValidateBuildButton();
            app.UpdateFactorLists()
        end

        % Value changed function: DesignmatrixDropDown
        function DesignmatrixDropDownValueChanged(app, event)
            app.ValidateBuildButton();
            app.UpdateFactorLists()
            
        end

        % Button pushed function: BuildmodelButton
        function BuildmodelButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure, 'Title', 'Building ASCA Model', ...
            'Message', 'Please wait while permutations are calculated...', ...
            'Indeterminate', 'on');
            X_name = app.DatamatrixDropDown.Value;
            F_name = app.DesignmatrixDropDown.Value;
            factor_namesVar = app.FactornamesDropDown.Value;

            % Get actual data from Workspace
            X = evalin('base', X_name);
            F = evalin('base', F_name);

            [valid, custom_names] = app.ValidateFactorNames(factor_namesVar, F);
        
            if ~valid
                uialert(app.UIFigure, ...
                    ['Factor names must be a 1D string/cell array with ' num2str(size(F,2)) ' elements.'], ...
                    'Validation Error');
                return; 
            end
                   
            prep = app.PreprocessingDropDown.Value;
            model_type = app.ModeltypeDropDown.Value;
            permutations = app.PermutationsSpinner.Value;

            numFactors = size(F, 2);
            ordinalVector = zeros(1, numFactors);
            randomVector = zeros(1, numFactors);
        
            nodesOrd = app.TreeOrdinal.CheckedNodes;
            for i = 1:length(nodesOrd)
                val = nodesOrd(i).NodeData;
                if isnumeric(val) 
                    ordinalVector(val) = 1;
                end
            end
        
            nodesRan = app.TreeRandom.CheckedNodes;
            for i = 1:length(nodesRan)
                val = nodesRan(i).NodeData;
                if isnumeric(val)
                    randomVector(val) = 1;
                end
            end

            if prep == "No preprocessing"
                prep = 0;
            elseif prep == "Mean-centering"
                prep = 1;
            elseif prep == "Autoscaling"
                prep = 2;
            else
                prep = -1;
            end
                
            nestedMatrix = app.GetNestedMatrix();

            [app.T, app.parglmo] = parglm(X, F, 'Preprocessing', prep, 'Model', model_type, ...
                'Permutations', permutations, 'Ordinal', ordinalVector, 'Random', randomVector, ...
                'Nested', nestedMatrix);


                if ~isempty(custom_names)
                    numFactors = length(custom_names);
                    f_labels = arrayfun(@(x) ['F' num2str(x) ': '], 1:numFactors, 'UniformOutput', false);
                    formatted_names = strcat(f_labels(:), cellstr(custom_names(:)));
                    app.T.Source(1:numFactors) = formatted_names;
                end

           % Display the validation table in the GUI
           app.UITable.Data = app.T;
           app.UITable.Enable = 'on';

           app.ascao = asca(app.parglmo);
           
           sources = app.T.Source;
           app.FactorInteractionDropDown.Items = sources;
           app.FactorDropDownLabel.Enable='on';
           app.FactorInteractionDropDown.Enable='on';
           app.PCAVisualizationButton.Enable='on';
            
           % Pass the generated variables to the workspace
           assignin('base', 'asca_table', app.T);
           assignin('base', 'parglmo', app.parglmo);
           assignin('base', 'ascao', app.ascao);


        end

        % Value changed function: FactornamesDropDown
        function FactornamesDropDownValueChanged(app, event)
            app.UpdateFactorLists();
            
        end

        % Button pushed function: PCAVisualizationButton
        function PCAVisualizationButtonButtonPushed(app, event)
            if isempty(app.ascao)
                uialert(app.UIFigure, 'Please build the model first.', 'No Model Found');
                return;
            end

            selectedName = app.FactorInteractionDropDown.Value;
            items = app.FactorInteractionDropDown.Items;
            idx = find(strcmp(items, selectedName));

            numFactors = length(app.ascao.factors);
            numInteractions = length(app.ascao.interactions);
            
            dataToProcess = [];
            
            if idx <= numFactors
                dataToProcess = app.ascao.factors{idx}.scoresV;
            elseif idx <= (numFactors + numInteractions)
                interIdx = idx - numFactors;
                dataToProcess = app.ascao.interactions{interIdx}.scoresV;
            else
                uialert(app.UIFigure, 'This source cannot be visualized with PCA.', 'Selection Info');
                return;
            end

            prepStr = app.PreprocessingDropDown.Value;
            if strcmp(prepStr, 'No preprocessing'), prepVal = 0;
            elseif strcmp(prepStr, 'Mean-centering'), prepVal = 1;
            else, prepVal = 2; % Autoscaling
            end

            try
                PCA(dataToProcess, [], 0);
            catch ME
                uialert(app.UIFigure, ['Error calling PCA GUI: ' ME.message], 'Launch Error');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.BuildASCAModelTab = uitab(app.TabGroup);
            app.BuildASCAModelTab.Title = 'Build ASCA Model';
            app.GridLayout3 = uigridlayout(app.BuildASCAModelTab);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Source'; 'SumSq'; 'PercSumSq'; 'df'; 'MeanSq'; 'F'; 'Pvalue'};
            app.UITable.RowName = {};
            app.UITable.Enable = 'off';
            app.BuildmodelButton = uibutton(app.GridLayout3, 'push');
            app.BuildmodelButton.ButtonPushedFcn = createCallbackFcn(app, @BuildmodelButtonPushed, true);
            app.BuildmodelButton.Enable = 'off';
            app.BuildmodelButton.Text = 'Build model';
            app.NestedfactorsPanel = uipanel(app.GridLayout3);
            app.NestedfactorsPanel.Title = 'Nested factors';
            app.GridLayout2 = uigridlayout(app.NestedfactorsPanel);
            app.ChildDropDown_1 = uidropdown(app.GridLayout2);
            app.ChildDropDown_1.Items = {};
            app.ChildDropDown_1.Value = {};
            app.ParentDropDown_1 = uidropdown(app.GridLayout2);
            app.ParentDropDown_1.Items = {};
            app.ParentDropDown_1.Value = {};
            app.nestedinLabel = uilabel(app.GridLayout2);
            app.nestedinLabel.Text = 'nested in';
            app.DatamatrixDropDownLabel = uilabel(app.GridLayout3);
            app.DatamatrixDropDownLabel.Text = 'Data matrix:';
            app.DatamatrixDropDown = uidropdown(app.GridLayout3);
            app.DatamatrixDropDown.Items = {};
            app.DatamatrixDropDown.ValueChangedFcn = createCallbackFcn(app, @DatamatrixDropDownValueChanged, true);
            app.DatamatrixDropDown.Value = {};
            app.DesignmatrixDropDownLabel = uilabel(app.GridLayout3);
            app.DesignmatrixDropDownLabel.Text = 'Design matrix:';
            app.DesignmatrixDropDown = uidropdown(app.GridLayout3);
            app.DesignmatrixDropDown.Items = {};
            app.DesignmatrixDropDown.ValueChangedFcn = createCallbackFcn(app, @DesignmatrixDropDownValueChanged, true);
            app.DesignmatrixDropDown.Value = {};
            app.FactornamesDropDownLabel = uilabel(app.GridLayout3);
            app.FactornamesDropDownLabel.Text = 'Factor names:';
            app.FactornamesDropDown = uidropdown(app.GridLayout3);
            app.FactornamesDropDown.Items = {};
            app.FactornamesDropDown.ValueChangedFcn = createCallbackFcn(app, @FactornamesDropDownValueChanged, true);
            app.FactornamesDropDown.Value = {};
            app.PreprocessingDropDownLabel = uilabel(app.GridLayout3);
            app.PreprocessingDropDownLabel.Text = 'Preprocessing:';
            app.PreprocessingDropDown = uidropdown(app.GridLayout3);
            app.PreprocessingDropDown.Items = {'Autoscaling', 'Mean-centering', 'No preprocessing'};
            app.PreprocessingDropDown.Value = 'Autoscaling';
            app.ModeltypeDropDownLabel = uilabel(app.GridLayout3);
            app.ModeltypeDropDownLabel.Text = 'Model type:';
            app.ModeltypeDropDown = uidropdown(app.GridLayout3);
            app.ModeltypeDropDown.Items = {'linear', 'interaction', 'full'};
            app.ModeltypeDropDown.Value = 'linear';
            app.PermutationsSpinnerLabel = uilabel(app.GridLayout3);
            app.PermutationsSpinnerLabel.Text = 'Permutations';
            app.PermutationsSpinner = uispinner(app.GridLayout3);
            app.PermutationsSpinner.Step = 100;
            app.PermutationsSpinner.Limits = [10 10000];
            app.PermutationsSpinner.Value = 1000;
            app.Panel = uipanel(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.Panel);
            app.TreeOrdinal = uitree(app.GridLayout4, 'checkbox');
            app.OrdinalfactorsNode_2 = uitreenode(app.TreeOrdinal);
            app.OrdinalfactorsNode_2.Text = 'Ordinal factors';
            app.TreeRandom = uitree(app.GridLayout4, 'checkbox');
            app.RandomfactorsNode = uitreenode(app.TreeRandom);
            app.RandomfactorsNode.Text = 'Random factors';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.GridLayoutVis = uigridlayout(app.VisualizationTab);
            app.PCAVisualizationButton = uibutton(app.GridLayoutVis, 'push');
            app.PCAVisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @PCAVisualizationButtonButtonPushed, true);
            app.PCAVisualizationButton.Enable = 'off';
            app.PCAVisualizationButton.Text = 'PCA Visualization';
            app.FactorInteractionDropDown = uidropdown(app.GridLayoutVis);
            app.FactorInteractionDropDown.Items = {};
            app.FactorInteractionDropDown.Enable = 'off';
            app.FactorInteractionDropDown.Value = {};
            app.FactorDropDownLabel = uilabel(app.GridLayoutVis);
            app.FactorDropDownLabel.Enable = 'off';
            app.FactorDropDownLabel.Text = 'Factor o interaction to plot';
            app.LoaddataButton = uibutton(app.GridLayout, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.RefreshButton = uibutton(app.GridLayout, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ASCA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end