classdef PCA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PCA_GUI           matlab.ui.Figure
        filemenu          matlab.ui.container.Menu
        loaddata          matlab.ui.container.Menu
        uipanelGen        matlab.ui.container.Panel
        selectPopup       matlab.ui.control.DropDown
        selectText        matlab.ui.control.Label
        generalPopup      matlab.ui.control.DropDown
        text26            matlab.ui.control.Label
        text25            matlab.ui.control.Label
        generalEdit       matlab.ui.control.EditField
        generalButton     matlab.ui.control.Button
        uipanelSum        matlab.ui.container.Panel
        sumText           matlab.ui.control.Label
        uipanelPrep       matlab.ui.container.Panel
        refreshbutton     matlab.ui.control.Button
        dataPopup         matlab.ui.control.DropDown
        prepPopup         matlab.ui.control.DropDown
        text4             matlab.ui.control.Label
        text2             matlab.ui.control.Label
        uipanelObs        matlab.ui.container.Panel
        labscorePopup     matlab.ui.control.DropDown
        classcorePopup    matlab.ui.control.DropDown
        text14            matlab.ui.control.Label
        text13            matlab.ui.control.Label
        uipanelSco        matlab.ui.container.Panel
        xpcscorePopup     matlab.ui.control.DropDown
        ypcscorePopup     matlab.ui.control.DropDown
        scoreButton       matlab.ui.control.Button
        text7             matlab.ui.control.Label
        text8             matlab.ui.control.Label
        uipanelOMEDA      matlab.ui.container.Panel
        omedaButton       matlab.ui.control.Button
        selomedaButton    matlab.ui.control.Button
        minusButton       matlab.ui.control.Button
        plusButton        matlab.ui.control.Button
        cleanButton       matlab.ui.control.Button
        trendButton       matlab.ui.control.Button
        modelomedaButton  matlab.ui.control.Button
        resomedaButton    matlab.ui.control.Button
        uipanelPCA        matlab.ui.container.Panel
        pcaButton         matlab.ui.control.Button
        text6             matlab.ui.control.Label
        pcEdit            matlab.ui.control.EditField
        uipanelVar        matlab.ui.container.Panel
        labvarPopup       matlab.ui.control.DropDown
        clasvarPopup      matlab.ui.control.DropDown
        text18            matlab.ui.control.Label
        text17            matlab.ui.control.Label
        modelmedaButton   matlab.ui.control.Button
        resmedaButton     matlab.ui.control.Button
        uipanelLoad       matlab.ui.container.Panel
        xpcvarPopup       matlab.ui.control.DropDown
        ypcvarPopup       matlab.ui.control.DropDown
        loadingButton     matlab.ui.control.Button
        text9             matlab.ui.control.Label
        text10            matlab.ui.control.Label
        uipanelMEDA       matlab.ui.container.Panel
        selmedaButton     matlab.ui.control.Button
        thresEdit         matlab.ui.control.EditField
        text5             matlab.ui.control.Label
        medaButton        matlab.ui.control.Button
        medaPopup         matlab.ui.control.DropDown
        uipanelInfo       matlab.ui.container.Panel
        infoText          matlab.ui.control.Label
        prevButton        matlab.ui.control.Button
        nextButton        matlab.ui.control.Button
    end

    
    methods (Access = private)
        function str = getCurrentPopupString(app, hh)
            % --- Function to get the current string in a popupmenu
            
            %# getCurrentPopupString returns the currently selected string in the popupmenu with handle hh
            
            %# could test input here
            if ~ishandle(hh) || strcmp(get(hh,'Type'),'popupmenu')
            error('getCurrentPopupString needs a handle to a popupmenu as input')
            end
            
            %# get the string - do it the readable way
            list = get(hh,'String');
            val = get(hh,'Value');
            if iscell(list)
               str = list{val};
            else
               str = list(val,:);
            end
        end
        
        function information_message(app, handles)
            % --- Fuction to show the corresponding message in the information panel
            
                switch handles.data.messageNum
                    case 0
                        text=sprintf('When you open the PCA GUI, the MATLAB workspace is automatically loaded into the inner GUI workspace, and you can interact with the data. At any point, you can reload the MATLAB workspace by clicking the ''REFRESH'' button, and restart your analysis.');
                    case 1
                        text=sprintf('Load Data section (top-left): To begin the analysis, choose a data matrix and select the preprocessing of the data from the corresponding drop down menu. If no dataset appears, please make sure the data is in the MATLAB workspace, then reload the GUI workspace with the ''REFRESH'' button.');
                    case 2
                        text=sprintf('Select PCs section (left): To aid in the selection of the number of PCs, enter a maximum number of PCs (e.g., write 10 in the ''PCs'' field) and select an option (''Var X + ckf'' is suggested). If ''SVI plot'' is selected, a variable should be additionally chosen. Then, press the plot button.');
                    case 3
                        text=sprintf('PCA Analysis section (left): Select the number of principal components for the model (field ''PCs'') and press the PCA button. This will fit the model and activate the Scores Plot, Loadings Plot and MEDA menus.');
                    case 4
                        text=sprintf('Exploratory plots of observations (Scores plots, oMEDA plots) and variables (Loadings plot, MEDA plots) and corresponding Model/Residuals plots, are issued by acting on the proper menu at the left (for observations) and right (for variables), respectively.');
                    case 5
                        text=sprintf('Observations section (down-left): ''Labels'' and ''Classes'' are cell arrays or vectors with as many tags as the number of observations. These are loaded to the GUI from the MATLAB workspace. Labels and classes apply to score plots and observation-wise Model/Residuals plots.');
                    case 6
                        text=sprintf('Variables section (down-right): ''Labels'' and ''Classes'' are cell arrays or vectors with as many entries as the number of variables. Labels and classes apply to loading plots, oMEDA and MEDA plots, and variable-wise Model/Residuals plots. ');
                    case 7
                        text=sprintf('You can create labels or classes in the MATLAB environment and load them to the GUI by clicking the REFRESH button (you will need to start over, make sure to select the proper dataset again)');
                    case 8
                        text=sprintf('To generate a scores plot, select the components (''X-axis'' and ''Y-axis''), optionally select a label and class array, and press ''Plot''. If you select the same component for both axes, a single-component bar plot will be issued.');
                    case 9
                        text=sprintf('To generate a loadings plot, follow the same approach. ');
                    case 10
                        text=sprintf('An oMEDA plot simplifies the understanding of the relationship between patterns in the scores and the correlated variables. It is like a biplot that combines scores and loadings, but is clearer and easier to interpret. With oMEDA, we can interpret outliers, trends, clusters, etc.');
                    case 11
                        text=sprintf('In the GUI, oMEDA works for a 2D scores plot, that you should have selected in the foreground (current figure). Create the 2D scores plot and press the ''SELECT'' button in the oMEDA section, and draw a polygon on top of the score plot to encircle one group of observations. Then, press the button ''+''.');
                    case 12
                        text=sprintf('If there is a second group of observations to compare, repeat the same operation and now press the button ''-''.');
                    case 13
                        text=sprintf('To issue the oMEDA plot, press the ''Plot'' button. This will allow you to understand the differences between the first group and the rest of observations (if you only selected one group) or between the two groups.');
                    case 14
                        text=sprintf('The MEDA plot is complementary to the loadings plots, and allows you to better understand the correlations among variables in specific subspaces of your model.');
                    case 15
                        text=sprintf('Select a subspace in the drop down menu (#A:#B, where A <= B both represent a PC) and press ''Plot''. You may modify this plot by activating ''Seriation'', to reorder the variables (suggested), and ''Discard'', to delete variables with less variance in the plot than the selected ''Threshold''.');
                    case 16
                        text=sprintf('You may also select a subgroup of variables in a loading plot to be displayed in MEDA, following the same approach as with oMEDA. All other modifiers (subspace, discard, seriation) will also apply.');
                    case 17
                        text=sprintf('Finally, use the ''Model'' and ''Residuals'' buttons to issue model/residuals aggregates for observations (left) and variables (right). In particular, visualizing the residuals is recommended, as no other plot before gave you detailed information about them.');
                    otherwise
                        disp('No case detected')
                end
                handles.data.text=cprint(handles.infoText,text,handles.data.text,0);
        end
        
        function loading_closereq(app, hObject, eventdata)
            % --- Function to be executed on closing a Loading Plot
            
            % hObject    handle to YourGuiName (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            selmedaButton = guidata(hObject);
            if isscalar(findobj('Tag','LoadingPlot'))
                if isvalid(selmedaButton)
                    set(selmedaButton,'Enable','off');
                end
            end
            shh=get(0,'ShowHiddenHandles');
            set(0,'ShowHiddenHandles','on');
            delete(get(0,'CurrentFigure'));
            set(0,'ShowHiddenHandles',shh);
        end
        
        function score_closereq(app, hObject, eventdata)
            % --- Function to be executed on closing a Score plot
            
            % hObject    handle to YourGuiName (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            selomedaButton = guidata(hObject);
            if isscalar(findobj('Tag','ScorePlot'))
                if isvalid(selomedaButton)
                    set(selomedaButton,'Enable','off');
                end
            end
            shh=get(0,'ShowHiddenHandles');
            set(0,'ShowHiddenHandles','on');
            delete(get(0,'CurrentFigure'));
            set(0,'ShowHiddenHandles',shh);
        end
        
        function handles = state_change(app, handles, state)
            % --- Change state of enabled elements in GUI, only main changes are
            % controlled, the rest is done in the code.
            
            
            generalSelection = getCurrentPopupString(app, handles.generalPopup);
            
            switch generalSelection
                case 'SVI plot'
                    set(handles.selectText,'Enable','on');
                    set(handles.selectPopup,'Enable','on');
                otherwise
                    set(handles.selectText,'Enable','off');
                    set(handles.selectPopup,'Enable','off');
            end
            
            switch state
            
                case 0
                    state_gen = 'off';
                    state_bas = 'off';
                    state_meda = 'off';
                    state_omeda = 'off';
            
                case 1
                    state_gen = 'on';
                    state_bas = 'off';
                    state_meda = 'off';
                    state_omeda = 'off';
            
                case 2
                    state_gen = 'on';
                    state_bas = 'on';
                    state_meda = 'off';
                    state_omeda = 'off';
            
            end
            
            %Score plot
            set(handles.text7,'Enable',state_bas);
            set(handles.text8,'Enable',state_bas);
            set(handles.xpcscorePopup,'Enable',state_bas);
            set(handles.ypcscorePopup,'Enable',state_bas);
            set(handles.text13,'Enable',state_bas);
            set(handles.text14,'Enable',state_bas);
            set(handles.classcorePopup,'Enable',state_bas);
            set(handles.labscorePopup,'Enable',state_bas);
            set(handles.scoreButton,'Enable',state_bas);
            
            %MEDA
            set(handles.medaPopup,'Enable',state_bas);
            set(handles.text5,'Enable',state_bas);
            set(handles.thresEdit,'Enable',state_bas);
            set(handles.discardRadio,'Enable',state_bas);
            set(handles.serRadio,'Enable',state_bas);
            set(handles.medaButton,'Enable',state_bas);
            set(handles.selmedaButton,'Enable',state_meda);
            
            %Loading plot
            set(handles.text9,'Enable',state_bas);
            set(handles.text10,'Enable',state_bas);
            set(handles.xpcvarPopup,'Enable',state_bas);
            set(handles.ypcvarPopup,'Enable',state_bas);
            set(handles.text17,'Enable',state_bas);
            set(handles.text18,'Enable',state_bas);
            set(handles.clasvarPopup,'Enable',state_bas);
            set(handles.labvarPopup,'Enable',state_bas);
            set(handles.medaButton,'Enable',state_bas);
            set(handles.loadingButton,'Enable',state_bas);
            
            %Residuals
            set(handles.resomedaButton,'Enable',state_bas);
            set(handles.resmedaButton,'Enable',state_bas);
            
            %Model
            set(handles.modelomedaButton,'Enable',state_bas);
            set(handles.modelmedaButton,'Enable',state_bas);
            
            %oMEDA
            set(handles.omedaButton,'Enable',state_omeda);
            set(handles.selomedaButton,'Enable',state_omeda);
            set(handles.minusButton,'Enable',state_omeda);
            set(handles.plusButton,'Enable',state_omeda);
            set(handles.cleanButton,'Enable',state_omeda);
            set(handles.trendButton,'Enable',state_omeda);
            
            %Preprocessing
            set(handles.generalPopup,'Enable',state_gen);
            set(handles.text26,'Enable',state_gen);
            set(handles.text25,'Enable',state_gen);
            set(handles.generalEdit,'Enable',state_gen);
            set(handles.generalButton,'Enable',state_gen);
            
            %General plots
            child=get(handles.uipanelPCA,'Children');
            for i=1:length(child)
                set(child(i),'Enable',state_gen);
            end
        end
        
        function handles = updateGui(app, eventdata, handles)
            
            if ~isempty(handles.data.WorkSpace)
            
                dataPopup_Callback(handles.dataPopup, eventdata, handles);
                handles = guidata(handles.dataPopup);
            
                set(handles.dataPopup, 'String', handles.data.WorkSpace);
                nombres=cellstr(get(handles.dataPopup,'String'));
                if ~isempty(handles.data.nameData)
                    val = 0;
                    for i=1:length(nombres)
                        if strcmp(nombres(i),handles.data.nameData)
                            val=i;
                        end
                    end
                    if val
                        set(handles.dataPopup,'Value',val);
                        handles.data.data_matrix=evalin('base',handles.data.WorkSpace{val});
                    end
                end
                %Para que la primera vez que se pulse Refresh con el workspace distinto
                %de vacio coja la primera matriz automaticamente
                if handles.data.control_Refresh==0 && isempty(handles.data.data_matrix)
                    string_evaluation=handles.data.WorkSpace{1};
                    data_matrix=evalin('base',string_evaluation);
                    handles.data.data_matrix=data_matrix;
                    handles.data.nameData=string_evaluation;
                end
            
                %Refresh the Label and Classes popupmenus:
                contents=get(handles.classcorePopup,'String');
                aux=[];
                for i=1:length(handles.data.WorkSpace)
                    aux=[aux handles.data.WorkSpace(i,:)];
                end
                a1=contents(1,:);
                for j=1:length(a1)
                    if ~isspace(a1(j))
                        b1(j)=a1(j);
                    end
                end
                aux=[b1,aux];
                set(handles.classcorePopup,'String',strvcat(aux));
                nombres=cellstr(get(handles.classcorePopup,'String'));
                if ~strcmp(handles.data.nameClasscore,'emptyclasses')
                    val = 0;
                    for i=1:length(nombres)
                        if strcmp(nombres(i),handles.data.nameClasscore)
                            val=i;
                        end
                    end
                    if val
                        set(handles.classcorePopup,'Value',val);
                        handles.data.classes=evalin('base',handles.data.WorkSpace{val-1});
                    end
                end
            
                contents=get(handles.labscorePopup,'String');
                aux2=[];
                for i=1:length(handles.data.WorkSpace)
                    aux2=[aux2 handles.data.WorkSpace(i,:)];
                end
                a2=contents(1,:);
                for j=1:length(a2)
                    if ~isspace(a2(j))
                        b2(j)=a2(j);
                    end
                end
                aux2=[b2,aux2];
                set(handles.labscorePopup,'String',strvcat(aux2));
                nombres=cellstr(get(handles.labscorePopup,'String'));
                if ~strcmp(handles.data.nameLabscore,'emptylabel')
                    val = 0;
                    for i=1:length(nombres)
                        if strcmp(nombres(i),handles.data.nameLabscore)
                            val=i;
                        end
                    end
                    if val
                        set(handles.labscorePopup,'Value',val);
                        handles.data.label=evalin('base',handles.data.WorkSpace{val-1});
                    end
                end
            
            
                contents=get(handles.clasvarPopup,'String');
                aux3=[];
                for i=1:length(handles.data.WorkSpace)
                    aux3=[aux3 handles.data.WorkSpace(i,:)];
                end
                a3=contents(1,:);
                for j=1:length(a3)
                    if ~isspace(a3(j))
                        b3(j)=a3(j);
                    end
                end
                aux3=[b3,aux3];
                set(handles.clasvarPopup,'String',strvcat(aux3));
                nombres=cellstr(get(handles.clasvarPopup,'String'));
                if ~strcmp(handles.data.nameClasvar,'emptyclasses')
                    val = 0;
                    for i=1:length(nombres)
                        if strcmp(nombres(i),handles.data.nameClasvar)
                            val=i;
                        end
                    end
                    if val
                        set(handles.clasvarPopup,'Value',val);
                        handles.data.classes_LP=evalin('base',handles.data.WorkSpace{val-1});
                    end
                end
            
                contents=get(handles.labvarPopup,'String');
                aux4=[];
                for i=1:length(handles.data.WorkSpace)
                    aux4=[aux4 handles.data.WorkSpace(i,:)];
                end
                a4=contents(1,:);
                for j=1:length(a4)
                    if ~isspace(a4(j))
                        b4(j)=a4(j);
                    end
                end
                aux4=[b4,aux4];
                set(handles.labvarPopup,'String',strvcat(aux4));
                nombres=cellstr(get(handles.labvarPopup,'String'));
                if ~strcmp(handles.data.nameLabvar,'emptylabel')
                    val = 0;
                    for i=1:length(nombres)
                        if strcmp(nombres(i),handles.data.nameLabvar)
                            val=i;
                        end
                    end
                    if val
                        set(handles.labvarPopup,'Value',val);
                        handles.data.label_LP=evalin('base',handles.data.WorkSpace{val-1});
                    end
                end
            
                handles.data.control_Refresh=1;
            else
                set(handles.dataPopup, 'String', ' ');
                handles.data.data_matrix=[];
            
                handles = state_change(app, handles,0);
            
                contents=get(handles.classcorePopup,'String');
                aux=[];
                aux=[contents(1,:),aux];
            
                contents=get(handles.labscorePopup,'String');
                aux2=[];
                aux2=[contents(1,:),aux2];
            
                contents=get(handles.clasvarPopup,'String');
                aux3=[];
                aux3=[contents(1,:),aux3];
            
                contents=get(handles.labvarPopup,'String');
                aux4=[];
                aux4=[contents(1,:),aux4];
            
                %TODO substitute by error dialog
                %Information panel:
                text=sprintf('Warning: No data matrices in workspace.');
                handles.data.sumtext=cprint(handles.sumText,text,handles.data.sumtext,0);
            end
            
            handles.data.classcore=aux;
            handles.data.labscore=aux2;
            handles.data.clasvar=aux3;
            handles.data.labvar=aux4;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PCA_OpeningFcn(app, varargin)
            % --- Executes just before PCA is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.PCA_GUI, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to PCA (see VARARGIN)
            
            % Choose default command line output for PCA
            handles.output = hObject;
            
            %Summary Panel:
            handles.data.sumtext = [];
            
            %Information Panel:
            handles.data.messageNum=0;
            handles.data.messageNum_max=17;
            handles.data.text=[];
            information_message(app, handles);
            
            %Variables initialization:
            handles.data.PCs=[];
            handles.data.PC1=[];
            handles.data.PC2=[];
            handles.data.weightDummy=cell(1,1000);
            handles.data.dummy={};
            handles.data.sp_ID_figures=[];
            handles.data.sp_matrix={};
            handles.data.clean_control=zeros(1,1000);
            handles.data.lp_ID_figures=[];
            handles.data.lp_matrix={};
            handles.data.PC1_LP=[];
            handles.data.PC2_LP=[];
            handles.data.control_Refresh=0;
            handles.data.CORTES={};
            handles.data.matrix_2PCs={};
            handles.data.PCs_MEDA='';
            handles.data.auxPCs=0;
            
            handles = state_change(app, handles,1);
            
            %When calling with input data
            if length(varargin) > 0 & ~isempty(varargin{1})
            
                routine=dbstack;
            
                handles.data.data_matrix = varargin{1};
            
                set(handles.dataPopup, 'String', 'X block');
                set(handles.dataPopup,'Enable','off');
                set(handles.dataPopup, 'Value', 1);
                dataPopup_Callback(handles.dataPopup, eventdata, handles);
                handles = guidata(handles.dataPopup);
            
                M = size(handles.data.data_matrix, 2);
                N = size(handles.data.data_matrix, 1);
            
                assert (N>1, 'Dimension Error: Number of rows should be higher than 1. Type ''help %s'' for more info.', routine(1).name);
                assert (M>1, 'Dimension Error: Number of columns should be higher than 1. Type ''help %s'' for more info.', routine(1).name);
            
                %set(handles.refreshbutton,'Enable','off');
            
                if length(varargin) > 1
            
                    handles.data.PCs = varargin{2};
            
                    if ~isempty(handles.data.PCs)
            
                        A = length(handles.data.PCs);
                        if size(handles.data.PCs,2) == 1, handles.data.PCs = handles.data.PCs'; end;
            
                        assert (isequal(size(handles.data.PCs), [1 A]), 'Dimension Error: 2nd argument must be 1-by-A. Type ''help %s'' for more info.', routine(1).name);
                        assert (isempty(find(handles.data.PCs<0)), 'Value Error: 2nd argument must not contain negative values. Type ''help %s'' for more info.', routine(1).name);
                        assert (isequal(fix(handles.data.PCs), handles.data.PCs), 'Value Error: 2nd argumentmust contain integers. Type ''help %s'' for more info.', routine(1).name);
            
                        set(handles.pcEdit,'String',num2str(max(handles.data.PCs)));
                        %set(handles.pcEdit,'Enable','off');
            
                        pcaButton_Callback(handles.pcaButton, eventdata, handles);
                        handles = guidata(handles.pcaButton);
            
                        %set(handles.pcaButton,'Enable','off');
            
                    end
            
                    if length(varargin) > 2 & ~isempty(varargin{3})
            
                        handles.data.prep = varargin{3};
            
                        assert (isequal(size(handles.data.prep), [1 1]), 'Dimension Error: 3th argument must be 1-by-1. Type ''help %s'' for more info.', routine(1).name);
            
                        set(handles.prepPopup,'Value',handles.data.prep+1);
            
                        prepPopup_Callback(handles.prepPopup, eventdata, handles);
                        handles = guidata(handles.prepPopup);
            
                        generalPopup_Callback(handles.generalPopup, eventdata, handles)
                        handles = guidata(handles.generalPopup);
            
                        set(handles.prepPopup,'Enable','off');
                    end
                end
            else
                dataPopup_Callback(handles.dataPopup, eventdata, handles);
                handles = guidata(handles.dataPopup);
            end;


            %Change icon
            %warning('off','MATLAB:HandleGraphics:ObsoletedProperty:JavaFrame');
            %javaFrame = get(hObject,'JavaFrame');
            %javaFrame.setFigureIcon(javax.swing.ImageIcon('icon.jpg'));
            
            movegui(hObject, 'onscreen')
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: classcorePopup
        function classcorePopup_Callback(app, event)
            % --- Executes on selection change in classcorePopup.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to classcorePopup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns classcorePopup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from classcorePopup
            
            incoming_data=get(hObject,'Value');
            string_evaluation=handles.data.classcore{incoming_data};
            handles.data.nameClasscore=string_evaluation;
            if strcmp(string_evaluation,'emptyclasses')
                classes = [];
                handles.data.classes = repmat({''},size(handles.data.data_matrix,1),1);
            else
                classes=evalin('base',string_evaluation);
                handles.data.classes=classes;
            
                if ~isempty(handles.data.classes)
                    if max(size(classes))~=size(handles.data.data_matrix,1) || min(size(classes))~=1
                        errordlg('Classes must have as many tags as number of observations in the data matrix.');
                        handles.data.nameClasscore='emptyclasses';
                        handles.data.classes=[];
                        nombres=cellstr(get(hObject,'String'));
                        for i=1:length(nombres)
                            if strcmp(nombres(i),'emptyclasses')
                                val=i;
                            end
                        end
                        set(hObject,'Value',val);
                    end
                end
            end
            
            guidata(hObject,handles);
        end

        % Value changed function: clasvarPopup
        function clasvarPopup_Callback(app, event)
            % --- Executes on selection change in clasvarPopup.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to clasvarPopup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns clasvarPopup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from clasvarPopup
            
            incoming_data=get(hObject,'Value');%Incoming data position
            string_evaluation=handles.data.clasvar{incoming_data};%Nombre correspondiente a la posiciï¿½n
            handles.data.nameClasvar=string_evaluation;
            if strcmp(string_evaluation,'emptyclasses')
                classes_LP = [];
                handles.data.classes_LP = repmat({''},size(handles.data.data_matrix,2),1);
            else
                classes_LP=evalin('base',string_evaluation);%Contenido de ese nombre(los datos en si)
                handles.data.classes_LP=classes_LP;
            
                if ~isempty(handles.data.classes_LP)
                    if max(size(classes_LP))~=size(handles.data.data_matrix,2) || min(size(classes_LP))~=1
                        errordlg('Classes must have as many entries as number of variables in the data matrix.');
                        handles.data.nameClasvar='emptyclasses';
                        handles.data.classes_LP=[];
                        nombres=cellstr(get(hObject,'String'));
                        for i=1:length(nombres)
                            if strcmp(nombres(i),'emptyclasses')
                                val=i;
                            end
                        end
                        set(hObject,'Value',val);
                    end
                end
            end
            
            guidata(hObject,handles);
        end

        % Button pushed function: cleanButton
        function cleanButton_Callback(app, event)
            % --- Executes on button press in cleanButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cleanButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            ID_list=get(0,'Children');
            ID=ID_list(2);%gcf del score plot que me interesa
            if ~isnumeric(ID)
                ID = ID.Number;
            end
            
            check_tag=get(ID,'Tag');
            if strcmp(check_tag,'ScorePlot')
                figure(ID);%Ya tengo el score plot pinchado(al que le quiero hacer oMEDA) en primera plana.
            else
                errordlg('To clean a figure this must be a Score Plot.');
                return;
            end
            
            clean_times=handles.data.clean_control(ID);
            
            a=get(ID,'Children');
            b=a(length(a));
            c=get(b,'Children');
            delete(c(1:clean_times));
            
            handles.data.clean_control(ID)=0;
            handles.data.weightDummy{1,ID}=[];
            %Dummy:
            M=size(handles.data.data_matrix,1);
            dummy=zeros(1,M);
            handles.data.dummy{1,ID}=dummy;
            handles.data.dummyRED=dummy;
            handles.data.dummyGREEN=dummy;
            
            guidata(hObject,handles);
        end

        % Value changed function: dataPopup
        function dataPopup_Callback(app, event)
            % --- Executes on selection change in dataPopup.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to dataPopup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns dataPopup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from dataPopup
            
            if isequal(get(handles.dataPopup,'Enable'),'on')
            
                if ~isempty(handles.data.WorkSpace)
                    handles = state_change(app, handles,1);
                else
                    handles = state_change(app, handles,0);
                    guidata(hObject, handles);
                    return
                end
            
                generalPopup_Callback(handles.generalPopup, eventdata, handles);
                handles = guidata(handles.generalPopup);
            
                incoming_data=get(hObject,'Value');%Incoming data position
                string_evaluation=handles.data.WorkSpace{incoming_data};%Name of the incoming data position
                data_matrix=evalin('base',string_evaluation);
                handles.data.data_matrix=data_matrix;
            
                [M N]=size(handles.data.data_matrix);
                %Summary Panel
                if isa(data_matrix,'double')
                    sumtext = sprintf('Data Loaded:\n%s - > <%dx%d>\nMin %d\nMax %d',string_evaluation,M,N,min(min(handles.data.data_matrix)),max(max(handles.data.data_matrix)));
                    handles.data.sumtext=cprint(handles.sumText,sumtext,handles.data.sumtext,0);
                else
                    sumtext = 'Data do not appear to be suited for PCA.';
                    handles.data.sumtext=cprint(handles.sumText,sumtext,handles.data.sumtext,0);
                end
            else
                [M N]=size(handles.data.data_matrix);
            end
            
            %Change the selectPopup
            cellPopup = cell(1,N);
            for i=1:N
                cellPopup{i} = num2str(i);
            end
            set(handles.selectPopup,'String',cellPopup);
            
            set(handles.labscorePopup,'Value',1);
            handles.data.label = repmat({''},size(handles.data.data_matrix,1),1);
            set(handles.classcorePopup,'Value',1);
            handles.data.classes = repmat({'1'},size(handles.data.data_matrix,1),1);
            set(handles.labvarPopup,'Value',1);
            handles.data.label_LP = repmat({''},size(handles.data.data_matrix,2),1);
            set(handles.clasvarPopup,'Value',1);
            handles.data.classes_LP = repmat({'1'},size(handles.data.data_matrix,2),1);
            
            guidata(hObject, handles);
        end

        % Button pushed function: generalButton
        function generalButton_Callback(app, event)
            % --- Executes on button press in generalButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to generalButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [pcs,status]=str2num(get(handles.generalEdit, 'String'));
            sizeMat = size(handles.data.data_matrix);
            pcs_int = uint8(pcs);
            if ~isscalar(pcs) || pcs ~= pcs_int
                errordlg('Please enter an integer number of PCs.');
                return;
            end
            if status == false
                errordlg('Please enter a number of PCs.');
                return;
            elseif pcs > sizeMat(2) || pcs < 1
                errordlg(sprintf('The number of PCs can not exceed the number of variables in the data matrix which is %d.',sizeMat(2)));
                return;
            end
            
            %Detect the selected general plot and plot it
            generalPlot = getCurrentPopupString(app, handles.generalPopup);
            switch generalPlot
                case 'Var X'
                    x_var = varPca(handles.data.data_matrix,'PCs',1:pcs,'Preprocessing',handles.data.prep);
                case 'Var X + ckf'
                    x_var = varPca(handles.data.data_matrix,'PCs',1:pcs,'Preprocessing',handles.data.prep,'PlotCkf',true);
                case 'ekf crossval '
                    [blocks_r blocks_c] = size(handles.data.data_matrix);
                    x_var = crossvalPca(handles.data.data_matrix,0:pcs,'ValProcedure','ekf','MaxSampleBlock',blocks_r,'MaxVarBlock',blocks_c,'Preprocessing',handles.data.prep);
                case 'SVI plot'
                    chosenVar = str2num(getCurrentPopupString(app, handles.selectPopup));
                    SVIplot(handles.data.data_matrix,'PCs',1:pcs,'Vars',chosenVar,'Groups',7,'Preprocessing',handles.data.prep);
                otherwise
                    disp('No case detected')
            end
        end

        % Value changed function: generalPopup
        function generalPopup_Callback(app, event)
            % --- Executes on selection change in generalPopup.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to generalPopup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns generalPopup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from generalPopup
            
            generalSelection = getCurrentPopupString(app, hObject);
            
            switch generalSelection
                case 'SVI plot'
                    set(handles.selectText,'Enable','on');
                    set(handles.selectPopup,'Enable','on');
                otherwise
                    set(handles.selectText,'Enable','off');
                    set(handles.selectPopup,'Enable','off');
            end
            guidata(hObject,handles);
        end

        % Value changed function: labscorePopup
        function labscorePopup_Callback(app, event)
            % --- Executes on selection change in labscorePopup.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to labscorePopup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns labscorePopup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from labscorePopup
            
            
            incoming_data=get(hObject,'Value');%Incoming data position
            string_evaluation=handles.data.labscore{incoming_data};
            handles.data.nameLabscore=string_evaluation;
            if strcmp(string_evaluation,'emptylabel')
                label = [];
                handles.data.label = repmat({''},size(handles.data.data_matrix,1),1);
            else
                label=evalin('base',string_evaluation);
                handles.data.label=label;
            
                if ~isempty(handles.data.label)
                    if max(size(label))~=size(handles.data.data_matrix,1) || min(size(label))~=1
                        errordlg('Labels must have as many tags as number of observations in the data matrix.');
                        handles.data.nameLabscore='emptylabel';
                        handles.data.label={};
                        nombres=cellstr(get(hObject,'String'));
                        for i=1:length(nombres)
                            if strcmp(nombres(i),'emptylabel')
                                val=i;
                            end
                        end
                        set(hObject,'Value',val);
                    end
                end
            end
            
            guidata(hObject,handles);
        end

        % Value changed function: labvarPopup
        function labvarPopup_Callback(app, event)
            % --- Executes on selection change in labvarPopup.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to labvarPopup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns labvarPopup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from labvarPopup
            
            incoming_data=get(hObject,'Value');
            string_evaluation=handles.data.labvar{incoming_data};
            handles.data.nameLabvar=string_evaluation;
            if strcmp(string_evaluation,'emptylabel')
                label_LP = [];
                handles.data.label_LP = repmat({''},size(handles.data.data_matrix,2),1);
            else
                label_LP=evalin('base',string_evaluation);
                handles.data.label_LP=label_LP;
            
                if ~isempty(handles.data.label_LP)
                    if max(size(label_LP))~=size(handles.data.data_matrix,2) || min(size(label_LP))~=1
                        errordlg('Labels must have as many tags as number of variables in the data matrix.');
                        handles.data.nameLabvar='emptylabel';
                        handles.data.label_LP={};
                        nombres=cellstr(get(hObject,'String'));
                        for i=1:length(nombres)
                            if strcmp(nombres(i),'emptylabel')
                                val=i;
                            end
                        end
                        set(hObject,'Value',val);
                    end
                end
            end
            
            guidata(hObject,handles);
        end

        % Menu selected function: loaddata
        function loaddata_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to loaddata (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Clear the command window for a cleaner display
            clc;
            
            % Display a message to the user
            text = 'Opening file selection dialog...';
            handles.data.sumtext=cprint(handles.sumText,text,handles.data.sumtext,0);
            
            % Use uigetfile to open a file selection dialog.
            % The filter specifies the file types that will be shown.
            % The filter format is: {'filter_spec', 'description'}
            % The 'MultiSelect' option allows the user to select multiple files if needed.
            [filename, pathname] = uigetfile(...
                {'*.txt;*.csv;*.xlsx', 'Data Files (*.txt, *.csv, *.xlsx)';
                 '*.txt',  'Text Files (*.txt)'; ...
                 '*.csv',  'CSV Files (*.csv)'; ...
                 '*.xlsx',  'Excel Files (*.xlsx)'; ...
                 '*.*',  'All Files (*.*)'}, ...
                'Select a Data File', ...
                'MultiSelect', 'off');
            
            % Check if the user selected a file or cancelled the dialog.
            % If the user cancels, uigetfile returns 0 for the filename.
            if isequal(filename, 0)
                text = 'User cancelled the file selection.';
                handles.data.sumtext=cprint(handles.sumText,text,handles.data.sumtext,0);
            else
                % Construct the full file path.
                fullFilePath = fullfile(pathname, filename);
            
                % Display the selected file path to the command window for confirmation.
                text = sprintf('File selected: %s\n', fullFilePath);
                handles.data.sumtext=cprint(handles.sumText,text,handles.data.sumtext,0);
            
                try
                    vars = loadCsv(fullFilePath);
            
                    % Get the names of the struct fields
                    handles.data.WorkSpace = fieldnames(vars);
            
                    % % Loop through each field and create a new variable
                    for i = 1:length(handles.data.WorkSpace)
                        field_name = handles.data.WorkSpace{i};
                        assignin('base',field_name,eval(['vars.' field_name]));
                    end
            
                    clear vars;
            
                    text = 'Data loaded successfully into the workspace.';
                    handles.data.sumtext=cprint(handles.sumText,text,handles.data.sumtext,0);
            
                catch ME
                    % Handle potential errors during the load process,
                    % such as an invalid file format.
                    errordlg(ME.message, 'File Load Error');
                    text = sprintf('Error loading file: %s\n', ME.message);
                    handles.data.sumtext=cprint(handles.sumText,text,handles.data.sumtext,0);
                end
            end
            
            handles = updateGui(app, eventdata, handles);
            
            % It's good practice to update the handles structure if you made any changes.
            guidata(hObject, handles);
        end

        % Button pushed function: loadingButton
        function loadingButton_Callback(app, event)
            % --- Executes on button press in medaButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to medaButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.data.PC1_LP=str2num(getCurrentPopupString(app, handles.xpcvarPopup));
            handles.data.PC2_LP=str2num(getCurrentPopupString(app, handles.ypcvarPopup));
            
            all_opened_graphs=get(0,'Children');
            new_lp_ID_figures=[];
            new_lp_matrix={};
            
            for i=1:length(handles.data.lp_ID_figures)
                if ~isempty(find(handles.data.lp_ID_figures(i)==all_opened_graphs,1))
                    new_lp_ID_figures=[new_lp_ID_figures handles.data.lp_ID_figures(i)];
                    new_lp_matrix={new_lp_matrix{:} handles.data.lp_matrix{:,i}};
                end
            end
            
            handles.data.lp_ID_figures=new_lp_ID_figures;%Identificadores de los Loadings Plots abiertos actualizado
            handles.data.lp_matrix=new_lp_matrix;
            
            P = loadingsPca (handles.data.data_matrix, 'PCs',[handles.data.PC1_LP handles.data.PC2_LP], 'Preprocessing',handles.data.prep, 'VarsLabel', handles.data.label_LP, 'VarsClass', handles.data.classes_LP);
            
            fig=gcf;
            %matrixPCs_MEDA_LP=[P(:,handles.data.PC1_LP),P(:,handles.data.PC2_LP)];
            P_size = size(P);
            if P_size(2) > 1
                matrixPCs_MEDA_LP=[P(:,1),P(:,2)];
            else
                matrixPCs_MEDA_LP=P(:,1);
            end
            handles.data.lp_ID_figures=[handles.data.lp_ID_figures fig];%Identificadores de los Score Plots abiertos
            handles.data.lp_matrix={handles.data.lp_matrix{:} matrixPCs_MEDA_LP};
            
            if ~(handles.data.PC1_LP == handles.data.PC2_LP)  && license('test', 'image_toolbox')
                set(handles.selmedaButton,'Enable','on');
                %Set new close funtion to new figure
                set(fig,'CloseRequestFcn',@app.loading_closereq)
                set(fig,'Tag','LoadingPlot');%A cada LoadingPlot que abro le pongo en su propiedad 'Tag' que es un LoadingPlot
                guidata(fig,handles.selmedaButton);
            else
                set(fig,'Tag','BarLoadingPlot');%A cada LoadingPlot que abro le pongo en su propiedad 'Tag' que es un LoadingPlot
            end
            
            guidata(hObject,handles);
        end

        % Button pushed function: medaButton
        function medaButton_Callback(app, event)
            % --- Executes on button press in medaButton.
            %medaButton==Plot (MEDA)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to medaButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.data.PCs_MEDA=getCurrentPopupString(app, handles.medaPopup);
            PCs_MEDA_cell = strread(handles.data.PCs_MEDA,'%s','delimiter',':');
            pcs = [str2num(PCs_MEDA_cell{1}):str2num(PCs_MEDA_cell{2})];
            if get(handles.serRadio,'Value')==0 && get(handles.discardRadio,'Value')==1
                handles.data.opt='101';
            else if get(handles.serRadio,'Value')==1 && get(handles.discardRadio,'Value')==0
                    handles.data.opt='110';
                else if get(handles.serRadio,'Value')==0 && get(handles.discardRadio,'Value')==0
                        handles.data.opt='100';
                    else handles.data.opt='111';
                    end
                end
            end
            
            
            handles.data.seriated = handles.serRadio.Value == 1;
            handles.data.discard = handles.discardRadio.Value == 1;
            
            [meda_map,meda_dis]=medaPca(handles.data.data_matrix,'PCs',pcs,'Preprocessing',handles.data.prep,'Threshold',handles.data.thres,'Seriated',handles.data.seriated,'Discard',handles.data.discard,'VarsLabel',handles.data.label_LP);
            
            guidata(hObject,handles);
        end

        % Value changed function: medaPopup
        function medaPopup_Callback(app, event)
            % --- Executes on selection change in medaPopup.
            %medaPopup==MEDA popupmenu
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to medaPopup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns medaPopup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from medaPopup
            
            PCs_MEDA_position=get(hObject,'Value');%Incoming data position
            contents=get(hObject,'String');
            PCs_MEDA=contents(PCs_MEDA_position,:);%Nombre correspondiente a la posiciÃ³n
            
            handles.data.PCs_MEDA=PCs_MEDA;
            
            guidata(hObject,handles);
        end

        % Button pushed function: minusButton
        function minusButton_Callback(app, event)
            % --- Executes on button press in minusButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to minusButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ID_list=get(0,'Children');
            ID=ID_list(2);%gcf del score plot que me interesa
            if ~isnumeric(ID)
                ID = ID.Number;
            end
            
            check_tag=get(ID,'Tag');
            if strcmp(check_tag,'ScorePlot')
                figure(ID);%Ya tengo el score plot pinchado(al que le quiero hacer oMEDA) en primera plana.
                hold on;
            else
                errordlg('To perform oMEDA you must select a Score Plot.');
                return;
            end
            
            N=size(handles.data.data_matrix,1);
            CortesVector=handles.data.CORTES{1,ID};
            matrix_2PCs=handles.data.matrix_2PCs{1,ID};
            
            handles.data.dummyRED = zeros(1,N);
            for l=1:N,
                if mod(CortesVector(l),2)==1
            
                    Xdata=matrix_2PCs(l,1);
                    Ydata=matrix_2PCs(l,2);
            
                    coord=plot(Xdata,Ydata);
                    set(coord,'marker','s');
                    %set(coord,'markersize',6);
                    set(coord,'markerfacecolor', [0 0 0]+0.9);
                    set(coord,'markeredgecolor','k');
            
                    %Dummy:
                    handles.data.dummyRED(l)=-1;
            
                    handles.data.clean_control(ID)=handles.data.clean_control(ID)+1;
                end
            end
            
            if isfield(handles.data,'dummyGREEN')
                handles.data.dummy{1,ID}=handles.data.dummyGREEN+handles.data.dummyRED;
            else
                handles.data.dummy{1,ID}=handles.data.dummyRED;
            end
            
            set(handles.omedaButton,'Enable','on');
            set(handles.trendButton,'Enable','on');
            guidata(hObject,handles);
        end

        % Button pushed function: modelmedaButton
        function modelmedaButton_Callback(app, event)
            % --- Executes on button press in modelmedaButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to modelmedaButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            E = leveragesPca(handles.data.data_matrix,'PCs',handles.data.PCs,'Preprocessing',handles.data.prep,'VarsLabel',handles.data.label_LP,'VarsClass',handles.data.classes_LP);
        end

        % Button pushed function: modelomedaButton
        function modelomedaButton_Callback(app, event)
            % --- Executes on button press in modelomedaButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to modelomedaButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [Dst,Qst,Dstt,Qstt,UCLd,UCLq] = mspcPca(handles.data.data_matrix,'PCs',min(handles.data.PCs):max(handles.data.PCs),'Preprocessing',handles.data.prep,'Plot',false,'ObsLabel',handles.data.label,'ObsClass',handles.data.classes);
            plotVec(Dst, 'EleLabel',handles.data.label, 'ObsClass',handles.data.classes, 'XYLabel',{[],'D-st'}, 'LimCont',UCLd);
        end

        % Button pushed function: nextButton
        function nextButton_Callback(app, event)
            % --- Executes on button press in nextButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to nextButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if handles.data.messageNum < handles.data.messageNum_max
                handles.data.messageNum = handles.data.messageNum +1;
                information_message(app, handles);
            end
            guidata(hObject,handles);
        end

        % Button pushed function: omedaButton
        function omedaButton_Callback(app, event)
            % --- Executes on button press in omedaButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to omedaButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ID_list=get(0,'Children');
            ID=ID_list(2);%gcf del score plot que me interesa
            if ~isnumeric(ID)
                ID = ID.Number;
            end
            
            check_tag=get(ID,'Tag');
            if strcmp(check_tag,'ScorePlot')
            
            else
                errordlg('To perform oMEDA you must select a Score Plot.');
                return;
            end
            
            if isequal(handles.data.dummy{1,ID},zeros(1,size(handles.data.dummy{1,ID},2))) && isempty(handles.data.weightDummy{1,ID})
                errordlg('To perform oMEDA you must select a Score Plot with at least one object selected.');
                return;
            end
            
            if ~isempty(handles.data.weightDummy{1,ID})
                handles.data.weightDummy{1,ID}=handles.data.weightDummy{1,ID}./abs(max(handles.data.weightDummy{1,ID}));
                omedaPca(handles.data.data_matrix,[min(handles.data.PC1,handles.data.PC2) max(handles.data.PC1,handles.data.PC2)],handles.data.data_matrix,handles.data.weightDummy{1,ID}','Preprocessing',handles.data.prep,'VarsLabel',handles.data.label_LP,'VarsClass',handles.data.classes_LP);
            else
                omedaPca(handles.data.data_matrix,[min(handles.data.PC1,handles.data.PC2) max(handles.data.PC1,handles.data.PC2)],handles.data.data_matrix,handles.data.dummy{1,ID}','Preprocessing',handles.data.prep,'VarsLabel',handles.data.label_LP,'VarsClass',handles.data.classes_LP);
            end
            
            guidata(hObject,handles);
        end

        % Value changed function: pcEdit
        function pcEdit_Callback(app, event)
            %edit text==PCs
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pcEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of pcEdit as text
            %        str2double(get(hObject,'String')) returns contents of pcEdit as a double
            PCs=str2num(get(hObject,'String'));
            
            handles.data.PCs = PCs;
            
            guidata(hObject,handles);
        end

        % Button pushed function: pcaButton
        function pcaButton_Callback(app, event)
            % --- Executes on button press in pcaButton.
            %pcaButton==PCA
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pcaButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %Information panel:
            if isempty(handles.data.data_matrix)
                errordlg('No data matrix selected, please select one.');
                return;
            end
            [pc_num,status]=str2num(get(handles.pcEdit, 'String'));
            pc_num_int = uint8(pc_num);
            if ~isscalar(pc_num) || pc_num ~= pc_num_int
                errordlg('Please enter an integer number of PCs.');
                return;
            end
            sizeMat = size(handles.data.data_matrix);
            if status == false
                errordlg('Please enter a number of PCs.');
                return;
            elseif pc_num > sizeMat(2) || pc_num < 1
                errordlg(sprintf('The number of PCs can not exceed the number of variables in the data matrix which is %d.',sizeMat(2)));
                return;
            end
            handles.data.PCs=[1:pc_num];
            
            %Si la variable handles.data.PCs es distinta de vacÃ­a, imprimir en xpcscorePopup,
            %xpcvarPopup, ypcvarPopup y ypcscorePopup los PCs posibles.
            if ~isempty(handles.data.PCs)
                set(handles.xpcscorePopup, 'Value',1);
                set(handles.ypcscorePopup, 'Value',1);
                set(handles.xpcvarPopup, 'Value',1);
                set(handles.ypcvarPopup, 'Value',1);
                set(handles.xpcscorePopup, 'String',handles.data.PCs);
                set(handles.ypcscorePopup, 'String',handles.data.PCs);
                set(handles.xpcvarPopup, 'String',handles.data.PCs);
                set(handles.ypcvarPopup, 'String',handles.data.PCs);
            
                %Imprimir en popupmenu de submenu MEDA todas las combinaciones posibles
                %para hacer MEDA
                k=min(handles.data.PCs);
                options=[];
                for i=min(handles.data.PCs):max(handles.data.PCs)
                    for j=k:max(handles.data.PCs)
                        options=[options,i,j];
                    end
                    k=k+1;
                end
            
                set(handles.medaPopup,'String','');
                for i=1:2:(length(options)-1)
                    contents=get(handles.medaPopup,'String');
                    set(handles.medaPopup,'String',strvcat(contents,sprintf('%d:%d',options(i),options(i+1))));
                end
            end
            
            if handles.data.auxPCs==0
            handles.data.PC1=min(handles.data.PCs);
            handles.data.PC2=min(handles.data.PCs);
            handles.data.PC1_LP=min(handles.data.PCs);
            handles.data.PC2_LP=min(handles.data.PCs);
            handles.data.PCs_MEDA=sprintf('%d:%d',min(handles.data.PCs),min(handles.data.PCs));
            handles.data.auxPCs=1;
            end
            
            handles = state_change(app, handles, 2);
            
            %Information panel:
            text=sprintf('Model generated successully!');
            handles.data.sumtext=cprint(handles.sumText,text,handles.data.sumtext,0);
            
            guidata(hObject,handles);
        end

        % Button pushed function: plusButton
        function plusButton_Callback(app, event)
            % --- Executes on button press in plusButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to plusButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            ID_list=get(0,'Children');
            ID=ID_list(2);%gcf del score plot que me interesa
            if ~isnumeric(ID)
                ID = ID.Number;
            end
            
            check_tag=get(ID,'Tag');
            if strcmp(check_tag,'ScorePlot')
                figure(ID);%Ya tengo el score plot pinchado(al que le quiero hacer oMEDA) en primera plana.
                hold on;
            else
                errordlg('To perform oMEDA you must select a Score Plot.');
                return;
            end
            
            N=size(handles.data.data_matrix,1);
            CortesVector=handles.data.CORTES{1,ID};
            matrix_2PCs=handles.data.matrix_2PCs{1,ID};
            
            handles.data.dummyGREEN = zeros(1,N);
            for l=1:N
                if mod(CortesVector(l),2)==1
                    Xdata=matrix_2PCs(l,1);
                    Ydata=matrix_2PCs(l,2);
            
                    coord=plot(Xdata,Ydata);
                    set(coord,'marker','o');
                    %set(coord,'markersize',6);
                    set(coord,'markerfacecolor',[0 0 0]+0.9);
                    set(coord,'markeredgecolor','k');
            
                    handles.data.dummyGREEN(l)=1;
                    handles.data.clean_control(ID)=handles.data.clean_control(ID)+1;
                end
            end
            
            if isfield(handles.data,'RED')
                handles.data.dummy{1,ID}=handles.data.dummyGREEN+handles.data.dummyRED;
            else
                handles.data.dummy{1,ID}=handles.data.dummyGREEN;
            end
            
            set(handles.omedaButton,'Enable','on');
            set(handles.trendButton,'Enable','on');
            guidata(hObject,handles);
        end

        % Value changed function: prepPopup
        function prepPopup_Callback(app, event)
            % --- Executes on selection change in prepPopup.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to prepPopup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns prepPopup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from prepPopup
            nombres=cellstr(get(hObject,'String'));
            val=nombres{get(hObject,'Value')};
            
            switch val
                case 'no preprocessing'
                    prep=0;
                    if handles.data.control==1
                        text = sprintf('Preprocessing of data matrix:\nNo preprocessing.');
                        handles.data.sumtext=cprint(handles.sumText,text,handles.data.text,0);
                    end
                case 'mean centering'
                    prep=1;
                    if handles.data.control==1
                        text = sprintf('Preprocessing of data matrix:\nMean Centering.');
                        handles.data.sumtext=cprint(handles.sumText,text,handles.data.text,0);
                    end
                case 'auto-scaling'
                    prep=2;
                    if handles.data.control==1
                        text = sprintf('Preprocessing of data matrix:\nAuto-scaling.');
                        handles.data.sumtext=cprint(handles.sumText,text,handles.data.text,0);
                    end
            end
            
            handles.data.prep = prep;
            handles.data.control=1;
            guidata(hObject,handles);
        end

        % Button pushed function: prevButton
        function prevButton_Callback(app, event)
            % --- Executes on button press in prevButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to prevButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if handles.data.messageNum > 0
                handles.data.messageNum = handles.data.messageNum -1;
                information_message(app, handles);
            end
            guidata(hObject,handles);
        end

        % Button pushed function: refreshbutton
        function refreshbutton_Callback(app, event)
            % --- Executes on button press in refreshbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to refreshbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            handles.data.WorkSpace=evalin('base','who');
            
            handles = updateGui(app, eventdata, handles);
            
            guidata(hObject,handles);
        end

        % Button pushed function: resmedaButton
        function resmedaButton_Callback(app, event)
            % --- Executes on button press in resmedaButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to resmedaButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            size_x = size(handles.data.data_matrix);
            PCs = 1:size_x(2);
            PCs(handles.data.PCs) = [];
            E = leveragesPca(handles.data.data_matrix,'PCs',PCs,'Preprocessing',handles.data.prep,'VarsLabel',handles.data.label_LP,'VarsClass',handles.data.classes_LP);
            ylabel('Residuals', 'FontSize', 16);
        end

        % Button pushed function: resomedaButton
        function resomedaButton_Callback(app, event)
            % --- Executes on button press in resomedaButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to resomedaButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [Dst,Qst,Dstt,Qstt,UCLd,UCLq] = mspcPca(handles.data.data_matrix,'PCs',min(handles.data.PCs):max(handles.data.PCs),'Preprocessing',handles.data.prep,'Plot',false,'ObsLabel',handles.data.label,'ObsClass',handles.data.classes);
            plotVec(Qst, 'EleLabel',handles.data.label,'ObsClass',handles.data.classes, 'XYLabel',{[],'Q-st'},'LimCont',UCLq);
        end

        % Button pushed function: scoreButton
        function scoreButton_Callback(app, event)
            % --- Executes on button press in scoreButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to scoreButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.data.PC1 = str2num(getCurrentPopupString(app, handles.xpcscorePopup));
            handles.data.PC2 = str2num(getCurrentPopupString(app, handles.ypcscorePopup));
            
            all_opened_graphs=get(0,'Children');
            new_sp_ID_figures=[];
            new_sp_matrix={};
            clean_ind=[];
            
            
            for i=1:length(handles.data.sp_ID_figures)
                if ~isempty(find(handles.data.sp_ID_figures(i)==all_opened_graphs,1)),
                    new_sp_ID_figures=[new_sp_ID_figures handles.data.sp_ID_figures(i)];
                    new_sp_matrix={new_sp_matrix{:} handles.data.sp_matrix{:,i}};
                else
                    clean_ind=[clean_ind i];%Identificadores de lo Score Plots cerrados
                    for j=1:length(clean_ind)
                        aux=clean_ind(j);
                        handles.data.clean_control(aux)=0;
                        handles.data.CORTES{1,aux}=[];
                        handles.data.weightDummy{1,aux}=[];
                        handles.data.matrix_2PCs{1,aux}=[];
                        %Dummy:
                        M=size(handles.data.data_matrix,1);
                        dummy=zeros(1,M);
                        handles.data.dummy{1,aux}=dummy;
                        handles.data.dummyRED=dummy;
                        handles.data.dummyGREEN=dummy;
                    end
                end
            end
            
            handles.data.sp_ID_figures=new_sp_ID_figures;%Vector actualizado con los identificadores de los Score Plots abiertos
            handles.data.sp_matrix=new_sp_matrix;
            
            T = scoresPca(handles.data.data_matrix,'PCs',[handles.data.PC1 handles.data.PC2],'Preprocessing',handles.data.prep,'ObsLabel',handles.data.label,'ObsClass',handles.data.classes);
            fig=gcf;
            
            %matrixPCs_oMEDA=[T(:,handles.data.PC1),T(:,handles.data.PC2)];
            T_size = size(T);
            if T_size(2) > 1
                matrixPCs_oMEDA=[T(:,1),T(:,2)];
                set(fig,'Tag','ScorePlot');%En la opciï¿½n etiqueta se indica que el grï¿½fico es un Score Plot
            else
                matrixPCs_oMEDA=T(:,1);
                set(fig,'Tag','BarScorePlot');%En la opciï¿½n etiqueta se indica que el grï¿½fico es un Score Plot
            end
            
            handles.data.sp_ID_figures=[handles.data.sp_ID_figures fig];%Vector con los identificadores de los Score Plots abiertos
            handles.data.sp_matrix={handles.data.sp_matrix{:} matrixPCs_oMEDA};
            
            %oMEDA (Select)
            if ~(handles.data.PC1 == handles.data.PC2) && license('test', 'image_toolbox')
                set(handles.selomedaButton,'Enable','on');
                %Set new close funtion to new figure
                set(gcf,'CloseRequestFcn',@app.score_closereq)
                guidata(gcf,handles.selomedaButton);
            end
            
            guidata(hObject,handles);
        end

        % Button pushed function: selmedaButton
        function selmedaButton_Callback(app, event)
            % --- Executes on button press in selmedaButton.
            %selmedaButton==Select (MEDA)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selmedaButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ID_list=get(0,'Children');
            ID=ID_list(2);%gcf del score plot que me interesa
            if ~isnumeric(ID)
                ID = ID.Number;
            end
            
            check_tag=get(ID,'Tag');
            if strcmp(check_tag,'LoadingPlot')
                figure(ID);%Ya tengo el score plot pinchado(al que le quiero hacer oMEDA) en primera plana.
                hold on;
            else
                errordlg('To perform MEDA over a Loading Plot you must select one 2D loading plot.');
                return;
            end
            
            %Ahora vamos a recuperar su matriz:
            %Voy a recorrer el vector de gcfs de score plots
            %handles.data.sp_ID_figures, para buscar en que posiciï¿½n esta el gcf ID.
            for i=1:length(handles.data.lp_ID_figures)
                if handles.data.lp_ID_figures(i)==ID
                    matrix_2PCs=handles.data.lp_matrix{:,i};
                end
            end
            
            irr_pol=impoly;
            vertex=getPosition(irr_pol);
            N=size(vertex,1);%Matrix size:
            %rows: number of vertex in the irregular polynomial
            %columns: this matrix contains always 2 colomns: one for the X
            %coordinate and the other one for the Y coordinate of each
            %vertex.
            
            %PASO 1:
            %Calcular los parï¿½metros A, B y C de la ecuaciï¿½n normal de la recta, para
            %todas las rectas que formen el polinomio irregular dibujado por el usuario
            A=[];
            B=[];
            C=[];
            for i=1:N%Desde 1 hasta el nï¿½mero de vï¿½rtices que tenga el polinomio
                %irregular, voy a hacer lo siguiente:
            
                %Coordenadas de un vï¿½rtice
                x1=vertex(i,1);
                y1=vertex(i,2);
            
                %Cooredenadas del siguiente vï¿½rtice:
                %El if controla el caso en que ya se hayan cogido todos los vï¿½rtices,
                %el vï¿½rtce en ese caso serï¿½ el primero de ellos, para cerrar la figura.
                if i==N
                    x2=vertex(1,1);
                    y2=vertex(1,2);
                else
                    x2=vertex(i+1,1);
                    y2=vertex(i+1,2);
                end
            
                %Coordenadas del vector director de la recta que une ambos vï¿½rtices:
                u1=x2-x1;
                u2=y2-y1;
            
                A=[A,u2];%Lista de u2(segunda coordenada del vector director)
                B=[B,-u1];%Lista de u1 (primera coordenada del vector director)
                c=(u1*y1)-(u2*x1);%Cï¿½lculo del parï¿½metro C de la ec.normal de la recta.
                C=[C,c];%Lista del parï¿½metro C, uno por recta.
            end
            
            %PASO 2:
            %Obtener los puntos de corte entre cada una de las anteriores rectas y la
            %semirrecta(paralela al eje X) que forma el punto del Score matrix a estudio.
            M=size(handles.data.data_matrix,2);%Number of observations in the score matrix.
            X=[];
            corte=0;
            CORTES=[];
            
            for j=1:M%All the observations from the Score Matrix: t
                Y=matrix_2PCs(j,2);
                corte=0;
                for k=1:N%Todas las rectas del poligono irregular
                    X=(-(B(k)*Y)-C(k))/A(k);
            
                    if k+1>N
                        if (Y>min(vertex(k,2),vertex(1,2)))&&(Y<max(vertex(k,2),vertex(1,2)))
                            if X>matrix_2PCs(j,1)
                                corte=corte+1;
                            end
                        end
                    else
                        if (Y>min(vertex(k,2),vertex(k+1,2)))&&(Y<max(vertex(k,2),vertex(k+1,2)))
                            if X>matrix_2PCs(j,1)
                                corte=corte+1;
                            end
                        end
                    end
                end
                CORTES=[CORTES,corte];
            end
            
            CortesVector=CORTES;
            vector_vars=[];
            for l=1:M
                if mod(CortesVector(l),2)==1
                    Xdata=matrix_2PCs(l,1);
                    Ydata=matrix_2PCs(l,2);
            
                    coord=plot(Xdata,Ydata);
                    set(coord,'marker','o');
                    %set(coord,'markersize',6);
                    set(coord,'markerfacecolor', [0 0 0]+0.9);
                    set(coord,'markeredgecolor', 'k');
            
                    vector_vars=[vector_vars l];
                end
            end
            
            
            handles.data.seriated = handles.serRadio.Value == 1;
            handles.data.discard = handles.discardRadio.Value == 1;
            
            [meda_map,meda_dis]=medaPca(handles.data.data_matrix,'PCs',[min(handles.data.PC1_LP,handles.data.PC2_LP) max(handles.data.PC1_LP,handles.data.PC2_LP)],'Preprocessing',handles.data.prep,'Threshold',handles.data.thres,'Seriated',handles.data.seriated,'Discard',handles.data.discard,'VarsLabel',handles.data.label_LP,'Vars',vector_vars);
            
            guidata(hObject,handles);
        end

        % Button pushed function: selomedaButton
        function selomedaButton_Callback(app, event)
            % --- Executes on button press in selomedaButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to selomedaButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            ID_list=get(0,'Children');
            ID=ID_list(2);%Identificador de la grï¿½fica seleccionada (debe ser un Score Plot).
            if ~isnumeric(ID)
                ID = ID.Number;
            end
            
            check_tag=get(ID,'Tag');
            if strcmp(check_tag,'ScorePlot')
                figure(ID);%Lanzar el Score Plot seleccionado para hacer oMEDA.
            else
                errordlg('To perform oMEDA you must select a 2D Score Plot.');
                return;
            end
            
            %Es necesario recuperar los datos del Score Plot seleccionado, es decir las observaciones ploteadas en el eje x e y:
            %Voy a recorrer el vector de gcfs de score plots que se llama
            %handles.data.sp_ID_figures, para buscar en que posiciï¿½n estï¿½ el gcf ID.
            for i=1:length(handles.data.sp_ID_figures)
                if handles.data.sp_ID_figures(i)==ID
                    % codigo de compr de que estï¿½ vacio
            %         ID
            %         size(handles.data.sp_matrix)
                    matrix_2PCs=handles.data.sp_matrix{:,i};
                end
            end
            
            irr_pol=impoly;
            vertex=getPosition(irr_pol);
            N=size(vertex,1);%Tamaï¿½o de la matriz:
            %filas: nï¿½mero de vï¿½rtices del polinomio irregular
            %columnas: contiene 2 columnas: coordenada x y coordenada y de cada vï¿½rtice.
            
            %PASO 1:
            %Calcular los parï¿½metros A, B y C de la ecuaciï¿½n normal de la recta, para
            %todas las rectas que formen el polinomio irregular dibujado por el usuario
            A=[];
            B=[];
            C=[];
            for i=1:N %Desde 1 hasta el nï¿½mero de vï¿½rtices que tenga el polinomio
                %irregular, voy a hacer lo siguiente:
            
                %Coordenadas de un vï¿½rtice:
                x1=vertex(i,1);
                y1=vertex(i,2);
            
                %Cooredenadas del siguiente vï¿½rtice:
                %El if controla el caso en que ya se hayan cogido todos los vï¿½rtices,
                %el vï¿½rtce en ese caso serï¿½ el primero de ellos, para cerrar la figura.
                if i==N
                    x2=vertex(1,1);
                    y2=vertex(1,2);
                else
                    x2=vertex(i+1,1);
                    y2=vertex(i+1,2);
                end
            
                %Coordenadas del vector director de la recta que une ambos vï¿½rtices:
                u1=x2-x1;
                u2=y2-y1;
            
                A=[A,u2];%Lista de u2(segunda coordenada del vector director)
                B=[B,-u1];%Lista de u1 (primera coordenada del vector director)
                c=(u1*y1)-(u2*x1);%Cï¿½lculo del parï¿½metro C de la ec.normal de la recta.
                C=[C,c];%Lista del parï¿½metro C, uno por recta.
            end
            
            %PASO 2:
            %Obtener los puntos de corte entre cada una de las anteriores rectas y la
            %semirrecta(paralela al eje X) que forma el punto del Score matrix a estudio.
            M=size(handles.data.data_matrix,1);%Number of observations in the score matrix.
            X=[];
            corte=0;
            CORTES=[];
            
            for j=1:M %Se recorren todas las observaciones
                Y=matrix_2PCs(j,2);
                corte=0;
                for k=1:N%Todas las rectas del poligono irregular
                    X=(-(B(k)*Y)-C(k))/A(k);
            
                    if k+1>N
                        if (Y>min(vertex(k,2),vertex(1,2)))&&(Y<max(vertex(k,2),vertex(1,2))),
                            if X>matrix_2PCs(j,1)
                                corte=corte+1;
                            end
                        end
                    else
                        if (Y>min(vertex(k,2),vertex(k+1,2)))&&(Y<max(vertex(k,2),vertex(k+1,2))),
                            if X>matrix_2PCs(j,1)
                                corte=corte+1;
                            end
                        end
                    end
                end
                CORTES=[CORTES,corte];
            end
            
            set(handles.minusButton,'Enable','on');
            set(handles.plusButton,'Enable','on');
            set(handles.cleanButton,'Enable','on');
            
            handles.data.CORTES{1,ID}=CORTES;
            handles.data.matrix_2PCs{1,ID}=matrix_2PCs;
            handles.data.clean_control(ID)=handles.data.clean_control(ID)+1;
            
            guidata(hObject,handles);
        end

        % Value changed function: thresEdit
        function thresEdit_Callback(app, event)
            %thresEdit==thresold
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to thresEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of thresEdit as text
            %        str2double(get(hObject,'String')) returns contents of thresEdit as a double
            thres=str2double(get(hObject,'String'));
            handles.data.thres = thres;
            guidata(hObject,handles);
        end

        % Button pushed function: trendButton
        function trendButton_Callback(app, event)
            % --- Executes on button press in trendButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to trendButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ID_list=get(0,'Children');
            ID=ID_list(2);%gcf del score plot que me interesa
            if ~isnumeric(ID)
                ID = ID.Number;
            end
            
            check_tag=get(ID,'Tag');
            if strcmp(check_tag,'ScorePlot')
                figure(ID);%Ya tengo el score plot pinchado(al que le quiero hacer oMEDA) en primera plana.
                hold on;
            else
                errordlg('To perform oMEDA you must select a Score Plot.');
                return;
            end
            
            matrix_2PCs=handles.data.matrix_2PCs{1,ID};
            trend_line=imline;
            setColor(trend_line,[0 0 0]);
            vertex_line=getPosition(trend_line);
            
            x1=vertex_line(1,1);
            y1=vertex_line(1,2);
            x2=vertex_line(2,1);
            y2=vertex_line(2,2);
            
            %Coordenadas del vector director de la recta que une ambos vï¿½rtices:
            u1=x2-x1;
            u2=y2-y1;
            
            %La ecuaciï¿½n de la recta tendencia es:
            A=u2;
            B=-u1;
            C=(u1*y1)-(u2*x1);
            
            %Quiero el punto de corte de la tendencia con la recta que va de la observaciï¿½n
            %a la lï¿½nea tendencia en perpendicular. Esto para cada una de las
            %observaciones.
            Cutoff_points=[];
            M=size(handles.data.data_matrix,1);
            for m=1:M
                p1=matrix_2PCs(m,1);
                p2=matrix_2PCs(m,2);
            
                %El vector director de la recta que va de la observacion a la
                %tendencia en perpendicular es:
                v1=A;
                v2=B;
            
                %La ecuacuaciï¿½n de la recta es:
                A2=v2;
                B2=-v1;
                C2=(v1*p2)-(v2*p1);
            
                %Ahora se obtiene el punto de corte de ambas rectas:
                %Ax+By+C=0;
                %A2x+B2y+C2=0;
                y_corte=(-C2+(A2/A)*C)/(((-A2/A)*B)+B2);
                x_corte=((-B*y_corte)-C)/A;
                Cutoff_points(m,1)=x_corte;
                Cutoff_points(m,2)=y_corte;
            end
            %Quedarse con la menor distancia entre un 1 y -1
            lowest_dist=Inf;
            ind1=1;
            ind2=1;
            dummy=handles.data.dummy{1,ID};
            for k=1:M
                if dummy(k)==1
                    %Coordenadas del punto que tiene asignado un 1 en la variable
                    %dummy
                    p1=Cutoff_points(k,1);
                    p2=Cutoff_points(k,2);
            
                    for l=1:M
                        if dummy(l)==-1
                            q1=Cutoff_points(l,1);
                            q2=Cutoff_points(l,2);
                            dist=sqrt((q1-p1)^2+(q2-p2)^2);
                            if dist< lowest_dist
                                lowest_dist=dist;
                                ind1=k;
                                ind2=l;
                            end
                        end
                    end
            
                end
            end
            
            %Construcciï¿½n de la nueva DUMMY con pesos:
            %Calcular el punto medio entre las observaciones mï¿½s cercanas obtenidas
            %enteriormente, este serï¿½ el nuevo cero para asignar pesos.
            c1=Cutoff_points(ind1,:);
            c2=Cutoff_points(ind2,:);
            NewCenter=(c1+c2)/2;
            
            %Asignaciï¿½n de pesos
            for m=1:M
                weights(m)=sum((Cutoff_points(m,:)-NewCenter).^2);
            end
            weightDummy=weights.*dummy;
            
            handles.data.weightDummy{1,ID}= weightDummy;
            handles.data.clean_control(ID)=handles.data.clean_control(ID)+1;
            
            guidata(hObject,handles);
        end

        % Value changed function: xpcscorePopup
        function xpcscorePopup_Callback(app, event)
            % --- Executes on selection change in xpcscorePopup.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to xpcscorePopup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns xpcscorePopup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from xpcscorePopup
            incoming_data_PC1=get(hObject,'Value');%Incoming data position
            handles.data.PC1=incoming_data_PC1;
            
            guidata(hObject,handles);
        end

        % Value changed function: xpcvarPopup
        function xpcvarPopup_Callback(app, event)
            % --- Executes on selection change in xpcvarPopup.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to xpcvarPopup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns xpcvarPopup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from xpcvarPopup
            incoming_data_PC1_LP=get(hObject,'Value');%Incoming data position
            handles.data.PC1_LP=incoming_data_PC1_LP;
            
            guidata(hObject,handles);
        end

        % Value changed function: ypcscorePopup
        function ypcscorePopup_Callback(app, event)
            % --- Executes on selection change in ypcscorePopup.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ypcscorePopup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns ypcscorePopup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from ypcscorePopup
            incoming_data_PC2=get(hObject,'Value');%Incoming data position
            handles.data.PC2=incoming_data_PC2;
            
            guidata(hObject,handles);
        end

        % Value changed function: ypcvarPopup
        function ypcvarPopup_Callback(app, event)
            % --- Executes on selection change in ypcvarPopup.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ypcvarPopup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns ypcvarPopup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from ypcvarPopup
            incoming_data_PC2_LP=get(hObject,'Value');%Incoming data position
            handles.data.PC2_LP=incoming_data_PC2_LP;
            
            guidata(hObject,handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PCA_GUI = uifigure('Visible', 'off');
            colormap(app.PCA_GUI, 'parula');
            app.PCA_GUI.Name = 'PCA';
            app.PCA_GUI.Tag = 'PCA';
            app.filemenu = uimenu(app.PCA_GUI);
            app.filemenu.Text = 'File';
            app.filemenu.Tag = 'filemenu';
            app.loaddata = uimenu(app.filemenu);
            app.loaddata.MenuSelectedFcn = createCallbackFcn(app, @loaddata_Callback, true);
            app.loaddata.Accelerator = 'N';
            app.loaddata.Text = 'Load';
            app.loaddata.Tag = 'loaddata';
            app.uipanelInfo = uipanel(app.PCA_GUI);
            app.uipanelInfo.Title = 'Information Panel';
            app.uipanelInfo.Tag = 'uipanelInfo';
            app.nextButton = uibutton(app.uipanelInfo, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButton_Callback, true);
            app.nextButton.Tag = 'nextButton';
            app.nextButton.Text = 'next->';
            app.prevButton = uibutton(app.uipanelInfo, 'push');
            app.prevButton.ButtonPushedFcn = createCallbackFcn(app, @prevButton_Callback, true);
            app.prevButton.Tag = 'prevButton';
            app.prevButton.Text = '<-prev';
            app.infoText = uilabel(app.uipanelInfo);
            app.infoText.Tag = 'infoText';
            app.infoText.Text = '';
            app.uipanelVar = uipanel(app.PCA_GUI);
            app.uipanelVar.Title = 'Variables';
            app.uipanelVar.Tag = 'uipanelVar';
            app.uipanelMEDA = uipanel(app.uipanelVar);
            app.uipanelMEDA.Title = 'MEDA';
            app.uipanelMEDA.Tag = 'uipanelMEDA';
            app.medaPopup = uidropdown(app.uipanelMEDA);
            app.medaPopup.Items = {};
            app.medaPopup.ValueChangedFcn = createCallbackFcn(app, @medaPopup_Callback, true);
            app.medaPopup.Tag = 'medaPopup';
            app.medaPopup.Value = {};
            app.medaButton = uibutton(app.uipanelMEDA, 'push');
            app.medaButton.ButtonPushedFcn = createCallbackFcn(app, @medaButton_Callback, true);
            app.medaButton.Tag = 'medaButton';
            app.medaButton.Text = 'Plot';
            app.text5 = uilabel(app.uipanelMEDA);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Threshold';
            app.thresEdit = uieditfield(app.uipanelMEDA, 'text');
            app.thresEdit.ValueChangedFcn = createCallbackFcn(app, @thresEdit_Callback, true);
            app.thresEdit.Tag = 'thresEdit';
            app.thresEdit.Value = '0.1';
            app.selmedaButton = uibutton(app.uipanelMEDA, 'push');
            app.selmedaButton.ButtonPushedFcn = createCallbackFcn(app, @selmedaButton_Callback, true);
            app.selmedaButton.Tag = 'selmedaButton';
            app.selmedaButton.Text = 'Select';
            app.uipanelLoad = uipanel(app.uipanelVar);
            app.uipanelLoad.Title = 'Loading Plot';
            app.uipanelLoad.Tag = 'uipanelLoad';
            app.text10 = uilabel(app.uipanelLoad);
            app.text10.Tag = 'text10';
            app.text10.Text = 'PC Y-axis';
            app.text9 = uilabel(app.uipanelLoad);
            app.text9.Tag = 'text9';
            app.text9.Text = 'PC X-axis';
            app.loadingButton = uibutton(app.uipanelLoad, 'push');
            app.loadingButton.ButtonPushedFcn = createCallbackFcn(app, @loadingButton_Callback, true);
            app.loadingButton.Tag = 'loadingButton';
            app.loadingButton.Text = 'Plot';
            app.ypcvarPopup = uidropdown(app.uipanelLoad);
            app.ypcvarPopup.Items = {};
            app.ypcvarPopup.ValueChangedFcn = createCallbackFcn(app, @ypcvarPopup_Callback, true);
            app.ypcvarPopup.Tag = 'ypcvarPopup';
            app.ypcvarPopup.Value = {};
            app.xpcvarPopup = uidropdown(app.uipanelLoad);
            app.xpcvarPopup.Items = {};
            app.xpcvarPopup.ValueChangedFcn = createCallbackFcn(app, @xpcvarPopup_Callback, true);
            app.xpcvarPopup.Tag = 'xpcvarPopup';
            app.xpcvarPopup.Value = {};
            app.resmedaButton = uibutton(app.uipanelVar, 'push');
            app.resmedaButton.ButtonPushedFcn = createCallbackFcn(app, @resmedaButton_Callback, true);
            app.resmedaButton.Tag = 'resmedaButton';
            app.resmedaButton.Text = 'Residuals';
            app.modelmedaButton = uibutton(app.uipanelVar, 'push');
            app.modelmedaButton.ButtonPushedFcn = createCallbackFcn(app, @modelmedaButton_Callback, true);
            app.modelmedaButton.Tag = 'modelmedaButton';
            app.modelmedaButton.Text = 'Model';
            app.text17 = uilabel(app.uipanelVar);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Colors';
            app.text18 = uilabel(app.uipanelVar);
            app.text18.Tag = 'text18';
            app.clasvarPopup = uidropdown(app.uipanelVar);
            app.clasvarPopup.Items = {'emptyclasses'};
            app.clasvarPopup.ValueChangedFcn = createCallbackFcn(app, @clasvarPopup_Callback, true);
            app.clasvarPopup.Tag = 'clasvarPopup';
            app.clasvarPopup.Value = 'emptyclasses';
            app.labvarPopup = uidropdown(app.uipanelVar);
            app.labvarPopup.Items = {'emptylabel'};
            app.labvarPopup.ValueChangedFcn = createCallbackFcn(app, @labvarPopup_Callback, true);
            app.labvarPopup.Tag = 'labvarPopup';
            app.labvarPopup.Value = 'emptylabel';
            app.uipanelPCA = uipanel(app.PCA_GUI);
            app.uipanelPCA.Title = 'PCA Analysis';
            app.uipanelPCA.Tag = 'uipanelPCA';
            app.pcEdit = uieditfield(app.uipanelPCA, 'text');
            app.pcEdit.ValueChangedFcn = createCallbackFcn(app, @pcEdit_Callback, true);
            app.pcEdit.Tag = 'pcEdit';
            app.text6 = uilabel(app.uipanelPCA);
            app.text6.Tag = 'text6';
            app.text6.Text = 'PCs';
            app.pcaButton = uibutton(app.uipanelPCA, 'push');
            app.pcaButton.ButtonPushedFcn = createCallbackFcn(app, @pcaButton_Callback, true);
            app.pcaButton.Tag = 'pcaButton';
            app.pcaButton.Text = 'PCA';
            app.uipanelObs = uipanel(app.PCA_GUI);
            app.uipanelObs.Title = 'Observations';
            app.uipanelObs.Tag = 'uipanelObs';
            app.resomedaButton = uibutton(app.uipanelObs, 'push');
            app.resomedaButton.ButtonPushedFcn = createCallbackFcn(app, @resomedaButton_Callback, true);
            app.resomedaButton.Tag = 'resomedaButton';
            app.resomedaButton.Text = 'Residuals';
            app.modelomedaButton = uibutton(app.uipanelObs, 'push');
            app.modelomedaButton.ButtonPushedFcn = createCallbackFcn(app, @modelomedaButton_Callback, true);
            app.modelomedaButton.Tag = 'modelomedaButton';
            app.modelomedaButton.Text = 'Model';
            app.uipanelOMEDA = uipanel(app.uipanelObs);
            app.uipanelOMEDA.Title = 'oMEDA';
            app.uipanelOMEDA.Tag = 'uipanelOMEDA';
            app.trendButton = uibutton(app.uipanelOMEDA, 'push');
            app.trendButton.ButtonPushedFcn = createCallbackFcn(app, @trendButton_Callback, true);
            app.trendButton.Tag = 'trendButton';
            app.trendButton.Text = 'Trend';
            app.cleanButton = uibutton(app.uipanelOMEDA, 'push');
            app.cleanButton.ButtonPushedFcn = createCallbackFcn(app, @cleanButton_Callback, true);
            app.cleanButton.Tag = 'cleanButton';
            app.cleanButton.Text = 'Clean';
            app.plusButton = uibutton(app.uipanelOMEDA, 'push');
            app.plusButton.ButtonPushedFcn = createCallbackFcn(app, @plusButton_Callback, true);
            app.plusButton.Tag = 'plusButton';
            app.plusButton.Text = '+';
            app.minusButton = uibutton(app.uipanelOMEDA, 'push');
            app.minusButton.ButtonPushedFcn = createCallbackFcn(app, @minusButton_Callback, true);
            app.minusButton.Tag = 'minusButton';
            app.minusButton.Text = '-';
            app.selomedaButton = uibutton(app.uipanelOMEDA, 'push');
            app.selomedaButton.ButtonPushedFcn = createCallbackFcn(app, @selomedaButton_Callback, true);
            app.selomedaButton.Tag = 'selomedaButton';
            app.selomedaButton.Text = 'Select';
            app.omedaButton = uibutton(app.uipanelOMEDA, 'push');
            app.omedaButton.ButtonPushedFcn = createCallbackFcn(app, @omedaButton_Callback, true);
            app.omedaButton.Tag = 'omedaButton';
            app.omedaButton.Text = 'Plot';
            app.uipanelSco = uipanel(app.uipanelObs);
            app.uipanelSco.Title = 'Score Plot';
            app.uipanelSco.Tag = 'uipanelSco';
            app.text8 = uilabel(app.uipanelSco);
            app.text8.Tag = 'text8';
            app.text8.Text = 'PC Y-axis';
            app.text7 = uilabel(app.uipanelSco);
            app.text7.Tag = 'text7';
            app.text7.Text = 'PC X-axis';
            app.scoreButton = uibutton(app.uipanelSco, 'push');
            app.scoreButton.ButtonPushedFcn = createCallbackFcn(app, @scoreButton_Callback, true);
            app.scoreButton.Tag = 'scoreButton';
            app.scoreButton.Text = 'Plot';
            app.ypcscorePopup = uidropdown(app.uipanelSco);
            app.ypcscorePopup.Items = {};
            app.ypcscorePopup.ValueChangedFcn = createCallbackFcn(app, @ypcscorePopup_Callback, true);
            app.ypcscorePopup.Tag = 'ypcscorePopup';
            app.ypcscorePopup.Value = {};
            app.xpcscorePopup = uidropdown(app.uipanelSco);
            app.xpcscorePopup.Items = {};
            app.xpcscorePopup.ValueChangedFcn = createCallbackFcn(app, @xpcscorePopup_Callback, true);
            app.xpcscorePopup.Tag = 'xpcscorePopup';
            app.xpcscorePopup.Value = {};
            app.text13 = uilabel(app.uipanelObs);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Colors';
            app.text14 = uilabel(app.uipanelObs);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Labels';
            app.classcorePopup = uidropdown(app.uipanelObs);
            app.classcorePopup.Items = {'emptyclasses'};
            app.classcorePopup.ValueChangedFcn = createCallbackFcn(app, @classcorePopup_Callback, true);
            app.classcorePopup.Tag = 'classcorePopup';
            app.classcorePopup.Value = 'emptyclasses';
            app.labscorePopup = uidropdown(app.uipanelObs);
            app.labscorePopup.Items = {'emptylabel'};
            app.labscorePopup.ValueChangedFcn = createCallbackFcn(app, @labscorePopup_Callback, true);
            app.labscorePopup.Tag = 'labscorePopup';
            app.labscorePopup.Value = 'emptylabel';
            app.uipanelPrep = uipanel(app.PCA_GUI);
            app.uipanelPrep.Title = 'Load Data';
            app.uipanelPrep.Tag = 'uipanelPrep';
            app.text2 = uilabel(app.uipanelPrep);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Data';
            app.text4 = uilabel(app.uipanelPrep);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Prep';
            app.prepPopup = uidropdown(app.uipanelPrep);
            app.prepPopup.Items = {'no preprocessing', 'mean centering', 'auto-scaling'};
            app.prepPopup.ValueChangedFcn = createCallbackFcn(app, @prepPopup_Callback, true);
            app.prepPopup.Tag = 'prepPopup';
            app.prepPopup.Value = 'mean centering';
            app.dataPopup = uidropdown(app.uipanelPrep);
            app.dataPopup.Items = {' '};
            app.dataPopup.ValueChangedFcn = createCallbackFcn(app, @dataPopup_Callback, true);
            app.dataPopup.Tag = 'dataPopup';
            app.dataPopup.Value = ' ';
            app.refreshbutton = uibutton(app.uipanelPrep, 'push');
            app.refreshbutton.ButtonPushedFcn = createCallbackFcn(app, @refreshbutton_Callback, true);
            app.refreshbutton.Tag = 'refreshbutton';
            app.refreshbutton.Text = 'Refresh';
            app.uipanelSum = uipanel(app.PCA_GUI);
            app.uipanelSum.Title = 'Summary Data';
            app.uipanelSum.Tag = 'uipanelSum';
            app.sumText = uilabel(app.uipanelSum);
            app.sumText.Tag = 'sumText';
            app.sumText.Text = '';
            app.uipanelGen = uipanel(app.PCA_GUI);
            app.uipanelGen.Title = 'Select PCs';
            app.uipanelGen.Tag = 'uipanelGen';
            app.generalButton = uibutton(app.uipanelGen, 'push');
            app.generalButton.ButtonPushedFcn = createCallbackFcn(app, @generalButton_Callback, true);
            app.generalButton.Tag = 'generalButton';
            app.generalButton.Text = 'Plot';
            app.generalEdit = uieditfield(app.uipanelGen, 'text');
            app.generalEdit.Tag = 'generalEdit';
            app.text25 = uilabel(app.uipanelGen);
            app.text25.Tag = 'text25';
            app.text25.Text = 'PCs';
            app.text26 = uilabel(app.uipanelGen);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Plot type';
            app.generalPopup = uidropdown(app.uipanelGen);
            app.generalPopup.Items = {'Var X', 'Var X + ckf', 'SVI plot', 'ekf crossval '};
            app.generalPopup.ValueChangedFcn = createCallbackFcn(app, @generalPopup_Callback, true);
            app.generalPopup.Tag = 'generalPopup';
            app.generalPopup.Value = 'Var X';
            app.selectText = uilabel(app.uipanelGen);
            app.selectText.Tag = 'selectText';
            app.selectText.Enable = 'off';
            app.selectText.Text = '# Var';
            app.selectPopup = uidropdown(app.uipanelGen);
            app.selectPopup.Items = {'Empty'};
            app.selectPopup.Tag = 'selectPopup';
            app.selectPopup.Enable = 'off';
            app.selectPopup.Value = 'Empty';
            % Show the figure after all components are created
            app.PCA_GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PCA(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PCA_GUI)

                % Execute the startup function
                runStartupFcn(app, @(app)PCA_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PCA_GUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PCA_GUI)
        end
    end
end